classdef auditoryResponseTester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        STARTButton            matlab.ui.control.Button
        STOPButton             matlab.ui.control.Button
        QUITButton             matlab.ui.control.Button
        SAVEButton             matlab.ui.control.Button
        PLAYButton             matlab.ui.control.Button
        RUNLampLabel           matlab.ui.control.Label
        RUNLamp                matlab.ui.control.Lamp
        OVRLampLabel           matlab.ui.control.Label
        OVRLamp                matlab.ui.control.Lamp
        AuditoryResponseTesterCAPRICEPLabel  matlab.ui.control.Label
        AboutButton            matlab.ui.control.Button
        CALIBRATIONPanel       matlab.ui.container.Panel
        TestsignalstartButton  matlab.ui.control.Button
        dBButton70             matlab.ui.control.Button
        dBButton80             matlab.ui.control.Button
        Lamp70dB               matlab.ui.control.Lamp
        Lamp80rab              matlab.ui.control.Lamp
        RESETButton            matlab.ui.control.Button
        CalLevelLabel          matlab.ui.control.Label
        CountDownLabel         matlab.ui.control.Label
        MEMO6sButton           matlab.ui.control.Button
        TEST_SIGNALPanel       matlab.ui.container.Panel
        CRSIGDropDownLabel     matlab.ui.control.Label
        CRSIGDropDown          matlab.ui.control.DropDown
        FRQEditFieldLabel      matlab.ui.control.Label
        FRQEditField           matlab.ui.control.EditField
        MIMICButton            matlab.ui.control.Button
        CIDDropDownLabel       matlab.ui.control.Label
        CIDDropDown            matlab.ui.control.DropDown
        RightPanel             matlab.ui.container.Panel
        READ_FileButton        matlab.ui.control.Button
        REPORTButton           matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        LevelBarAxis           matlab.ui.control.UIAxes
        PowerAxis              matlab.ui.control.UIAxes
        OrthgnlAxis            matlab.ui.control.UIAxes
        RespAxis               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SignalData % Signal related common data
        TestSignalData % Test signal related data
        AnalysisData % Analysis related data
    end
    
    methods (Access = private)
        
        function results = initializeAudioInput(app)
            results = [];
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            f = msgbox({'Please select the working directory.'; ...
                'Then, please select the audio device.'}, 'Initialization','help');
            app.SignalData.workingDirectory = string(uigetdir);
            fs = 44100;
            testDuration = 20;
            app.SignalData.testDuration = testDuration;
            %bufferLength = 1024;
            recorderObj = audioPlayerRecorder;
            devices = getAudioDevices(recorderObj);
            [deviceID,tf] = listdlg('ListString', ...
                devices, 'SelectionMode','single');
            app.SignalData.audioMode = 'simultaneous';
            if ~tf
                close(f);
                close(app.UIFigure);
            else
                logFileName = "audRespLog" + datestr(now, 30) + ".txt";
                app.SignalData.logFileFid = ...
                    fopen(app.SignalData.workingDirectory + "/" + logFileName, "a");
                app.SignalData.Device = devices{deviceID};
                app.SignalData.recorder = audioPlayerRecorder(fs,"RecorderChannelMapping", [1 2], ...
                    "BitDepth", "24-bit integer", "Device", devices{deviceID});
                bufferLength = get(app.SignalData.recorder, "BufferSize");
                app.SignalData.samplingFrequency = fs;
                app.SignalData.bufferSize = bufferLength;
                app.SignalData.Driver = 'simultaneousIO';
                %--- log test condition
                fprintf(app.SignalData.logFileFid, 'Computer: %s\n', computer);
                fprintf(app.SignalData.logFileFid, 'Device: %s\n', devices{deviceID});
                fprintf(app.SignalData.logFileFid, 'Driver: %s\n', app.SignalData.Driver);
                fprintf(app.SignalData.logFileFid, 'Sampling frequency: %8.0f Hz\n', fs);
                %---
                nTmpBuffer = 8;
                app.SignalData.nTmpBuffer = nTmpBuffer;
                app.SignalData.running = "off";
                app.SignalData.signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
                timeAxis = ((1:nTmpBuffer * bufferLength) - nTmpBuffer * bufferLength) / fs;
                tt = (0:1/fs:testDuration)' - testDuration;
                app.SignalData.wholeWave = (rand(length(tt), 1) - 0.5) * 0.001;
                fftl = length(timeAxis);
                app.SignalData.fftl = fftl;
                %---- for level bar graph
                app.SignalData.levelBarHandle = bar(app.LevelBarAxis, -20 + 90, ...
                    "FaceColor", [0 1 0]);
                hold(app.LevelBarAxis, "on")
                app.SignalData.peakLineHandle = plot(app.LevelBarAxis, ...
                    [0 2], -10 + [1 1] + 90, 'r', 'linewidth', 3);
                axis(app.LevelBarAxis,[0 2 0 90])
                ylabel(app.LevelBarAxis, 'Input Level (dB rel MSB)')
                set(app.LevelBarAxis, 'ytick', 0:10:90, 'ytickLabel', ...
                    num2str((-90:10:0)'));
                hold(app.LevelBarAxis, "on")
                grid(app.LevelBarAxis, "on")
                set(app.SignalData.levelBarHandle, 'Interruptible','off', ...
                    'BusyAction', 'cancel');
                set(app.SignalData.peakLineHandle, 'Interruptible','off', ...
                    'BusyAction', 'cancel');
                app.SignalData.calibrationLevel = 0;
                %---- prepare loop pink noise
                fftlPink = 2 ^ ceil(log2(fs * 1.4));
                x = pinknoise(fftlPink * 8);
                segment = x((1:fftlPink*2) + fftlPink * 2) .* sqrt(hanning(fftlPink * 2));
                y = x * 0;
                for ii = 0:fftlPink:length(x) - fftlPink * 2
                    y(ii + (1:2*fftlPink)) = y(ii + (1:2*fftlPink)) + segment;
                end
                app.SignalData.loopPink = y(2 * fftlPink + (1:2*fftlPink));
                disp(length(app.SignalData.loopPink));
                close(f);
            end
        end
        
        function results = updateLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            %fftl = app.SignalData.fftl;
            app.SignalData.running = "on";
            nTmpBuffer = app.SignalData.nTmpBuffer;
            signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
            postIdx = 1:(nTmpBuffer - 1) * bufferLength;
            preIdx = postIdx + bufferLength;
            %captureIdx = (nTmpBuffer - 1) * bufferLength + (1:bufferLength);
            app.SignalData.totalOverrun = 0;
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            app.SignalData.overRunCounter = 0;
            indexWholePre = 1:length(app.SignalData.wholeWave) - bufferLength;
            %tmpOut = zeros(bufferLength, 1);
            testSignal = app.TestSignalData.testSignal;
            testSignal = testSignal / max(abs(testSignal)) * 0.8;
            headLoc = 0;
            app.SignalData.outCopy = app.SignalData.wholeWave;
            %---- speed test
            strtTic = tic;
            nTest = 40;
            for ii = 1:nTest
                tmpOut = testSignal(headLoc + (1:bufferLength));
                headLoc = headLoc + bufferLength;
                signalBuffer(postIdx) = signalBuffer(preIdx);
                inputData = tmpOut;
                numOverrun = 1; 
                if 1 == 2
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                app.SignalData.wholeWave(indexWholePre) = ...
                    app.SignalData.wholeWave(indexWholePre + bufferLength);
                app.SignalData.wholeWave(end - bufferLength + 1:end) = ...
                    inputData;
                app.SignalData.outCopy(indexWholePre) = ...
                    app.SignalData.outCopy(indexWholePre + bufferLength);
                app.SignalData.outCopy(end - bufferLength + 1:end) = ...
                    tmpOut;
                end
                app.SignalData.wholeWave(headLoc + (1:bufferLength)) = ...
                    inputData;
                app.SignalData.outCopy(headLoc + (1:bufferLength)) = ...
                    tmpOut;
                %signalBuffer = sgramUpdateBody(app, inputData, captureIdx, signalBuffer);
            end
            speedResult = toc(strtTic) / nTest;
            if speedResult > 0.015
                disp(['This machine is too slow. Realtime spectrogram disabled. ' ...
                    num2str(speedResult * 1000) ' ms'])
            else
                disp(['Update time:' num2str(speedResult * 1000) ' ms'])
            end
            %---- speed test end
            totalLength = 0;
            headLoc = 0;
            timeLeft = length(app.SignalData.wholeWave) / fs;
            app.CountDownLabel.Visible = "on";
            app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
            app.SignalData.outCopy = app.SignalData.wholeWave * 0;
            while app.SignalData.running == "on" && totalLength ...
                    < length(app.SignalData.wholeWave) - bufferLength
                tmpOut = testSignal(headLoc + (1:bufferLength));
                signalBuffer(postIdx) = signalBuffer(preIdx);
                [inputData,numOverrun] = app.SignalData.recorder([tmpOut tmpOut]);
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                if 1 == 2
                app.SignalData.wholeWave(indexWholePre) = ...
                    app.SignalData.wholeWave(indexWholePre + bufferLength);
                app.SignalData.wholeWave(end - bufferLength + 1:end) = ...
                    inputData;
                app.SignalData.outCopy(indexWholePre) = ...
                    app.SignalData.outCopy(indexWholePre + bufferLength);
                app.SignalData.outCopy(end - bufferLength + 1:end) = ...
                    tmpOut;
                end
                app.SignalData.wholeWave(headLoc + (1:bufferLength)) = ...
                    inputData(:,1);
                app.SignalData.outCopy(headLoc + (1:bufferLength)) = ...
                    inputData(:,2); %tmpOut;
                %signalBuffer = sgramUpdateBody(app, inputData, captureIdx, signalBuffer);
                timeLeft = timeLeft - bufferLength / fs;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
                totalLength = min(length(app.SignalData.wholeWave), totalLength + bufferLength);
                headLoc = headLoc + bufferLength;
            end
            app.SignalData.recorder([tmpOut tmpOut] * 0);
            app.CountDownLabel.Visible = "off";
            app.SignalData.totalLength = totalLength;
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
        end
        
        function signalBuffer = sgramUpdateBody(app, inputData, captureIdx, signalBuffer)
            signalBuffer(captureIdx) = inputData;
            set(app.SignalData.levelBarHandle, 'ydata', ...
                20 * log10(std(signalBuffer)) + 90);
            set(app.SignalData.peakLineHandle, 'ydata', ...
                20 * log10(max(abs(signalBuffer))) * [1, 1] + 90);
            %--- spectrogram view update
            if app.SignalData.totalOverrun > 0 && app.SignalData.overRunCounter == 0
                app.OVRLamp.Visible = 'on';
                app.SignalData.overRunCounter = 20;
            elseif app.SignalData.overRunCounter > 0
                app.SignalData.overRunCounter = app.SignalData.overRunCounter - 1;
                if app.SignalData.overRunCounter == 0
                    app.SignalData.totalOverrun = 0;
                end
            else
                app.OVRLamp.Visible = 'off';
            end
        end
        
        function results = recordLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            %fftl = app.SignalData.fftl;
            memoLength = 2^round(log2(6*fs));
            memoBuffer = zeros(memoLength, 1);
            app.SignalData.totalOverrun = 0;
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            app.SignalData.overRunCounter = 0;
            indexWholePre = 1:memoLength - bufferLength;
            tmpOut = zeros(bufferLength, 1);
            totalLength = 0;
            timeLeft = memoLength / fs;
            app.CountDownLabel.Visible = "on";
            app.CountDownLabel.Enable = "on";
            app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
            drawnow
            while totalLength < memoLength - bufferLength
                [inputData,numOverrun] = app.SignalData.recorder(tmpOut);
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                memoBuffer(indexWholePre) = ...
                    memoBuffer(indexWholePre + bufferLength);
                memoBuffer(end - bufferLength + 1:end) = ...
                    inputData(:,1);
                timeLeft = timeLeft - bufferLength / fs;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
                totalLength = min(memoLength, totalLength + bufferLength);
            end
            app.CountDownLabel.Visible = "off";
            app.SignalData.totalLength = totalLength;
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            fileNameRoot = "audRespMemo" + datestr(now, 30);
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                memoBuffer, app.SignalData.samplingFrequency, "BitsPerSample", 24);
            fprintf(app.SignalData.logFileFid, 'Memo saved: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'File name: %s\n', fileNameRoot + ".wav");
        end
        
        function results = initializeTestSignal(app)
            %results = [];
            app.TestSignalData = load("testSigBase400ms.mat");
            fo = str2double(app.FRQEditField.Value);
            carrierType = app.CRSIGDropDown.Value;
            xin = app.SignalData.wholeWave * 0;
            app.TestSignalData.combiID = str2double(app.CIDDropDown.Value);
            output = generateTestFMSignal(app.TestSignalData, fo, ...
                carrierType, app.TestSignalData.combiID, 25, xin);
            app.TestSignalData.testSignal = output.testSignal;
            results = app;
        end
        
        function results = initializeAnalysisViewer(app)
            results = [];
            testSignal = app.TestSignalData.testSignal;
            fs = app.SignalData.samplingFrequency;
            nData = length(testSignal);
            tx = (1:nData)'/fs;
            %--- Power plot
            app.AnalysisData.sigPowerHandle = ...
                plot(app.PowerAxis, tx, 10*log10(0.8^2) * ones(nData, 1), 'LineWidth', 2);
            hold(app.PowerAxis, 'all');
            app.AnalysisData.refPowerHandle = ...
                plot(app.PowerAxis, tx, 10*log10(0.6^2) * ones(nData, 1), 'LineWidth', 2);
            axis(app.PowerAxis, [0 tx(end) -80 0]);
            xlabel(app.PowerAxis, 'time (x)');
            ylabel(app.PowerAxis, 'level (dB rel. MSB)')
            title(app.PowerAxis, 'signal power')
            %--- Orthogonalized signal plot
            app.AnalysisData.sigOrthHandle = ...
                plot(app.OrthgnlAxis, tx, ones(nData, 1), 'LineWidth', 2);
            hold(app.OrthgnlAxis, 'all');
            app.AnalysisData.refOrthHandle = ...
                plot(app.OrthgnlAxis, tx,  -10 + ones(nData, 1), 'LineWidth', 2);
            app.AnalysisData.bgOrthHandle = ...
                plot(app.OrthgnlAxis, tx,  -5 + ones(nData, 1), 'LineWidth', 2);
            axis(app.OrthgnlAxis, [0 tx(end) -75 75]);
            xlabel(app.OrthgnlAxis, 'time (x)');
            ylabel(app.OrthgnlAxis, 'deviation (cent)')
            title(app.OrthgnlAxis, 'orthogonalized modulation')
            %--- Response plot
            app.AnalysisData.sigRespHandle = ...
                plot(app.RespAxis, tx, ones(nData, 1), 'LineWidth', 2);
            hold(app.RespAxis, 'all');
            app.AnalysisData.refRespHandle = ...
                plot(app.RespAxis, tx,  -10 + ones(nData, 1), 'LineWidth', 2);
            app.AnalysisData.bgRespHandle = ...
                plot(app.RespAxis, tx,  -5 + ones(nData, 1), 'LineWidth', 2);
            axis(app.RespAxis, [0 tx(end) -75 75]);
            xlabel(app.RespAxis, 'time (x)');
            ylabel(app.RespAxis, 'deviation (cent)')
            title(app.RespAxis, 'input and response with background')
        end
        
        function results = analysisResponse(app)
            results = [];
            output = foResponseToAuditoryMod(app.SignalData.fullpath, 0);
            signal = output.signal;
            fo = output.fo;
            fs = output.fs;
            fileInfo = output.fileInfo;
            %----
            %set(app.Label, 'Text', {fileInfo.Filename; ...
            %    ['fo:' fileInfo.Comment ' Hz  Type:' fileInfo.Artist ...
            %    '  CAL:' num2str(output.calibrationConst) ' dB']})
            title(app.PowerAxis, {fileInfo.Filename; ...
                ['fo:' fileInfo.Comment ' Hz  Type:' fileInfo.Artist ...
                '  CAL:' num2str(output.calibrationConst) ' dB  Set: ' ...
                num2str(output.combination)]})
            %----
            dt = 1/fs;
            cumPower = cumsum(signal .^ 2) * dt;
            tx = (1:length(signal))'/fs;
            powerSq = (interp1(tx, cumPower, tx + 0.5/fo, 'linear', "extrap") ...
                - interp1(tx, cumPower, tx - 0.5/fo, 'linear', "extrap")) / fo;
            %disp(size(get(app.AnalysisData.refPowerHandle, 'ydata')))
            %disp(size(powerSq))
            set(app.AnalysisData.refPowerHandle, 'ydata', ...
                10*log10(powerSq(:,2)), 'xdata', tx);
            set(app.AnalysisData.sigPowerHandle, 'ydata', ...
                10*log10(powerSq(:,1)), 'xdata', tx);
            axis(app.PowerAxis, [0 tx(end) -120 0])
            %---
            set(app.AnalysisData.sigOrthHandle, 'ydata', ...
                output.recovered4Signal, 'xdata', tx);
            set(app.AnalysisData.refOrthHandle, 'ydata', ...
                output.recovered4Pulse, 'xdata', tx);
            set(app.AnalysisData.bgOrthHandle, 'ydata', ...
                output.backGound, 'xdata', tx);
            %---
            respRefSeg = output.av4Pulse(tx>6 & tx < 7);
            txSeg = tx(tx>6 & tx < 7);
            [~, mxIdx] = max(respRefSeg);
            maxTime = txSeg(mxIdx);
            set(app.AnalysisData.sigRespHandle, 'ydata', ...
                output.av4Signal, 'xdata', tx - maxTime);
            set(app.AnalysisData.refRespHandle, 'ydata', ...
                output.av4Pulse, 'xdata', tx - maxTime);
            set(app.AnalysisData.bgRespHandle, 'ydata', ...
                output.avBackGround, 'xdata', tx - maxTime);
            minSig = min(output.av4Signal(tx>6 & tx < 8.5));
            maxPulse = max(output.av4Pulse(tx>6 & tx < 8.5));
            axis(app.RespAxis, [[6 8.5]- maxTime min(-5, minSig - 3) maxPulse + 5]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAudioInput(app);
            app = initializeTestSignal(app);
            initializeAnalysisViewer(app);
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'A/D started: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Test signal: %s\n', app.CRSIGDropDown.Value);
            fprintf(app.SignalData.logFileFid, 'Test fo: %s\n', app.FRQEditField.Value);
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.MEMO6sButton.Enable = 'off';
            disableDefaultInteractivity(app.LevelBarAxis);
            app.TestsignalstartButton.Enable = 'off';
            updateLoop(app);
            fprintf(app.SignalData.logFileFid, 'A/D stopped: %s\n', datestr(now));
            enableDefaultInteractivity(app.LevelBarAxis);
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.MEMO6sButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.SignalData.running = "off";
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            disp("BYE...")
            if isfield(app.SignalData, 'logFileFid')
                fclose(app.SignalData.logFileFid);
            end
            pause(0.5);
            close(app.UIFigure);
        end

        % Button pushed function: TestsignalstartButton
        function TestsignalstartButtonPushed(app, event)
            disableDefaultInteractivity(app.LevelBarAxis);
            bufferLength = app.SignalData.bufferSize;
            baseIdx = 1:bufferLength;
            initialPoint = 0;
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Test Signal started: %s\n', datestr(now));
            totalUnderRun = 0;
            app.PLAYButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.dBButton70.Enable = 'on';
            app.dBButton80.Enable = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            pause(0.1)
            varInput = 0;
            sigLength = length(app.SignalData.loopPink);
            app.SignalData.loopBuffer = zeros(sigLength, 1);
            preIdx = 1:sigLength - bufferLength;
            postIdx = (1:sigLength - bufferLength) + bufferLength;
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            while app.SignalData.running == "on"
                app.SignalData.loopBuffer(preIdx) = app.SignalData.loopBuffer(postIdx);
                tmpOut = app.SignalData.loopPink(initialPoint + baseIdx);
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        [audioIn, numUnderRun] = app.SignalData.recorder(tmpOut);
                    case 'independent'
                        [audioIn, numUnderRun1] = app.SignalData.recorder();
                        numUnderRun = app.SignalData.player(tmpOut) + numUnderRun1;
                end
                app.SignalData.loopBuffer(sigLength - bufferLength + baseIdx) = audioIn(:,1);
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
                varInput = varInput + std(audioIn(:,1)) .^ 2;
                initialPoint = rem(initialPoint, sigLength);
                set(app.SignalData.levelBarHandle, 'ydata', ...
                    20 * log10(std(app.SignalData.loopBuffer(pickerIdx))) + 90);
                set(app.SignalData.peakLineHandle, 'ydata', ...
                    20 * log10(max(abs(app.SignalData.loopBuffer(pickerIdx)))) * [1, 1] + 90);
                drawnow limitrate
            end
            pause(0.1);
            release(app.SignalData.recorder);
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.RUNLamp.Visible = 'off';
            enableDefaultInteractivity(app.LevelBarAxis);
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton80
        function dBButton80Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 80 - signalDB;
            app.Lamp80rab.Visible = 'on';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            fprintf(app.SignalData.logFileFid, 'Calibration 80dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton70
        function dBButton70Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 70 - signalDB;
            app.Lamp70dB.Visible = 'on';
            fprintf(app.SignalData.logFileFid, 'Calibration 70dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.TestsignalstartButton.Enable = 'on';
            app.RESETButton.Enable = 'off';
            app.SignalData.calibrationLevel = 0;
            app.Lamp80rab.Visible = 'off';
            app.Lamp70dB.Visible = 'off';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            bufferLength = app.SignalData.bufferSize;
            baseIdx = 1:bufferLength;
            initialPoint = max(0, length(app.SignalData.wholeWave) ...
                - app.SignalData.totalLength);
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Played back: %s\n', datestr(now));
            totalUnderRun = 0;
            app.PLAYButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            pause(0.1)
            %varInput = 0;
            while initialPoint < length(app.SignalData.wholeWave) - bufferLength
                tmpOut = app.SignalData.wholeWave(initialPoint + baseIdx);
                [~, numUnderRun] = app.SignalData.recorder(tmpOut);
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
            end
            pause(0.1)
            release(app.SignalData.recorder);
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.RUNLamp.Visible = 'off';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            fileNameRoot = "audResp" + datestr(now, 30);
            initialPoint = max(0, length(app.SignalData.wholeWave) ...
                - app.SignalData.totalLength);
            outData = [app.SignalData.wholeWave(initialPoint + 1:end), ...
                app.SignalData.outCopy(initialPoint + 1:end)];
            commentText = [app.FRQEditField.Value ' ' num2str(app.SignalData.calibrationLevel, '%8.2f')];
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                outData, app.SignalData.samplingFrequency, "BitsPerSample", 24, ...
                "Comment",commentText,"Artist", app.CRSIGDropDown.Value, ...
                "Title", app.CIDDropDown.Value);
            fprintf(app.SignalData.logFileFid, 'Data saved: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'File name: %s\n', fileNameRoot + ".wav");
            app.SAVEButton.Enable = 'off';
            app.SignalData.fullpath = app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav";
            analysisResponse(app);
        end

        % Button pushed function: MEMO6sButton
        function MEMO6sButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'Memo started: %s\n', datestr(now));
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.MEMO6sButton.Enable = 'off';
            app.TestsignalstartButton.Enable = 'off';
            recordLoop(app);
            fprintf(app.SignalData.logFileFid, 'Memo stopped: %s\n', datestr(now));
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.MEMO6sButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Value changed function: CRSIGDropDown
        function CRSIGDropDownValueChanged(app, event)
            %value = app.CRSIGDropDown.Value;
            initializeTestSignal(app);
        end

        % Value changed function: FRQEditField
        function FRQEditFieldValueChanged(app, event)
            %value = app.FRQEditField.Value;
            initializeTestSignal(app);
            analysisResponse(app);
        end

        % Button pushed function: READ_FileButton
        function READ_FileButtonPushed(app, event)
            [file,path] = uigetfile('audResp*.wav');
            app.SignalData.fullpath = [path file];
            analysisResponse(app);
        end

        % Button pushed function: REPORTButton
        function REPORTButtonPushed(app, event)
            %exportgraphics(app.RightPanel, 'test.jpg')
            set(app.Label, 'Text', datestr(now))
            fileName = char(app.SignalData.fullpath);
            fileName = [fileName(1:end-4) 'Cpy.jpg'];
            exportapp(app.UIFigure, fileName);
            set(app.Label, 'Text', ' ')
        end

        % Value changed function: CIDDropDown
        function CIDDropDownValueChanged(app, event)
            %value = app.CIDDropDown.Value;
            initializeTestSignal(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {774, 774};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.SAVEButton = uibutton(app.LeftPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.PLAYButton = uibutton(app.LeftPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.RUNLampLabel = uilabel(app.LeftPanel);
            app.RUNLampLabel.Text = 'RUN';
            app.RUNLamp = uilamp(app.LeftPanel);
            app.RUNLamp.Visible = 'off';
            app.OVRLampLabel = uilabel(app.LeftPanel);
            app.OVRLampLabel.Text = 'OVR';
            app.OVRLamp = uilamp(app.LeftPanel);
            app.OVRLamp.Visible = 'off';
            app.AuditoryResponseTesterCAPRICEPLabel = uilabel(app.LeftPanel);
            app.AuditoryResponseTesterCAPRICEPLabel.Text = {'Auditory'; 'Response'; 'Tester'; '(CAPRICEP)'};
            app.AboutButton = uibutton(app.LeftPanel, 'push');
            app.AboutButton.Text = 'About';
            app.CALIBRATIONPanel = uipanel(app.LeftPanel);
            app.CALIBRATIONPanel.Title = 'CALIBRATION';
            app.TestsignalstartButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.TestsignalstartButton.ButtonPushedFcn = createCallbackFcn(app, @TestsignalstartButtonPushed, true);
            app.TestsignalstartButton.Text = 'Test signal start';
            app.dBButton70 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton70.ButtonPushedFcn = createCallbackFcn(app, @dBButton70Pushed, true);
            app.dBButton70.Enable = 'off';
            app.dBButton70.Text = '70dB';
            app.dBButton80 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton80.ButtonPushedFcn = createCallbackFcn(app, @dBButton80Pushed, true);
            app.dBButton80.Enable = 'off';
            app.dBButton80.Text = '80dB';
            app.Lamp70dB = uilamp(app.CALIBRATIONPanel);
            app.Lamp70dB.Visible = 'off';
            app.Lamp80rab = uilamp(app.CALIBRATIONPanel);
            app.Lamp80rab.Visible = 'off';
            app.RESETButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.CalLevelLabel = uilabel(app.CALIBRATIONPanel);
            app.CalLevelLabel.Text = '0';
            app.CountDownLabel = uilabel(app.LeftPanel);
            app.CountDownLabel.Enable = 'off';
            app.CountDownLabel.Visible = 'off';
            app.CountDownLabel.Text = '0';
            app.MEMO6sButton = uibutton(app.LeftPanel, 'push');
            app.MEMO6sButton.ButtonPushedFcn = createCallbackFcn(app, @MEMO6sButtonPushed, true);
            app.MEMO6sButton.Text = 'MEMO5s';
            app.TEST_SIGNALPanel = uipanel(app.LeftPanel);
            app.TEST_SIGNALPanel.Title = 'TEST_SIGNAL';
            app.CRSIGDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.CRSIGDropDownLabel.Text = 'CRSIG';
            app.CRSIGDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.CRSIGDropDown.Items = {'SINE', 'SINES', 'MFND', 'MFNDH'};
            app.CRSIGDropDown.ValueChangedFcn = createCallbackFcn(app, @CRSIGDropDownValueChanged, true);
            app.CRSIGDropDown.Value = 'SINE';
            app.FRQEditFieldLabel = uilabel(app.TEST_SIGNALPanel);
            app.FRQEditFieldLabel.Text = 'FRQ';
            app.FRQEditField = uieditfield(app.TEST_SIGNALPanel, 'text');
            app.FRQEditField.ValueChangedFcn = createCallbackFcn(app, @FRQEditFieldValueChanged, true);
            app.FRQEditField.Value = '130';
            app.MIMICButton = uibutton(app.TEST_SIGNALPanel, 'push');
            app.MIMICButton.Text = 'MIMIC';
            app.CIDDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.CIDDropDownLabel.Text = 'CID';
            app.CIDDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.CIDDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.CIDDropDown.ValueChangedFcn = createCallbackFcn(app, @CIDDropDownValueChanged, true);
            app.CIDDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.READ_FileButton = uibutton(app.RightPanel, 'push');
            app.READ_FileButton.ButtonPushedFcn = createCallbackFcn(app, @READ_FileButtonPushed, true);
            app.READ_FileButton.Text = 'READ_File';
            app.REPORTButton = uibutton(app.RightPanel, 'push');
            app.REPORTButton.ButtonPushedFcn = createCallbackFcn(app, @REPORTButtonPushed, true);
            app.REPORTButton.Text = 'REPORT';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = ' ';
            app.LevelBarAxis = uiaxes(app.RightPanel);
            title(app.LevelBarAxis, 'Level')
            ylabel(app.LevelBarAxis, 'Y')
            zlabel(app.LevelBarAxis, 'Z')
            app.LevelBarAxis.XTick = [];
            app.LevelBarAxis.XTickLabel = '';
            app.PowerAxis = uiaxes(app.RightPanel);
            title(app.PowerAxis, 'd')
            xlabel(app.PowerAxis, 'X')
            ylabel(app.PowerAxis, 'Y')
            zlabel(app.PowerAxis, 'Z')
            app.OrthgnlAxis = uiaxes(app.RightPanel);
            title(app.OrthgnlAxis, 'd')
            xlabel(app.OrthgnlAxis, 'X')
            ylabel(app.OrthgnlAxis, 'Y')
            zlabel(app.OrthgnlAxis, 'Z')
            app.RespAxis = uiaxes(app.RightPanel);
            title(app.RespAxis, 'd')
            xlabel(app.RespAxis, 'X')
            ylabel(app.RespAxis, 'Y')
            zlabel(app.RespAxis, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auditoryResponseTester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end