classdef auditoryResponseTesterChor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PitchMonitorPanel           matlab.ui.container.Panel
        PitchMonitorAxes            matlab.ui.control.UIAxes
        VoicecheckButton            matlab.ui.control.Button
        TEST_SIGNALPanel            matlab.ui.container.Panel
        SAVEtestsignalButton        matlab.ui.control.Button
        NormalizationDropDown       matlab.ui.control.DropDown
        NormalizationDropDownLabel  matlab.ui.control.Label
        VOICEFreqDropDown           matlab.ui.control.DropDown
        VOICEFreqDropDownLabel      matlab.ui.control.Label
        TESTFreqDropDown            matlab.ui.control.DropDown
        TESTFreqDropDownLabel       matlab.ui.control.Label
        CombinationIDDropDown       matlab.ui.control.DropDown
        CombinationIDDropDownLabel  matlab.ui.control.Label
        UpdatesettingButton         matlab.ui.control.Button
        SignalTypeDropDown          matlab.ui.control.DropDown
        SignalTypeDropDownLabel     matlab.ui.control.Label
        MEMO6sButton                matlab.ui.control.Button
        CountDownLabel              matlab.ui.control.Label
        CALIBRATIONPanel            matlab.ui.container.Panel
        CalLevelLabel               matlab.ui.control.Label
        RESETButton                 matlab.ui.control.Button
        Lamp80rab                   matlab.ui.control.Lamp
        Lamp70dB                    matlab.ui.control.Lamp
        dBButton80                  matlab.ui.control.Button
        dBButton70                  matlab.ui.control.Button
        TestsignalstartButton       matlab.ui.control.Button
        AboutButton                 matlab.ui.control.Button
        AuditoryResponseTesterCAPRICEPLabel  matlab.ui.control.Label
        OVRLamp                     matlab.ui.control.Lamp
        OVRLampLabel                matlab.ui.control.Label
        RUNLamp                     matlab.ui.control.Lamp
        RUNLampLabel                matlab.ui.control.Label
        PLAYButton                  matlab.ui.control.Button
        SAVEButton                  matlab.ui.control.Button
        QUITButton                  matlab.ui.control.Button
        STOPButton                  matlab.ui.control.Button
        STARTButton                 matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        REPORTButton                matlab.ui.control.Button
        READ_FileButton             matlab.ui.control.Button
        RespAxis                    matlab.ui.control.UIAxes
        OrthgnlAxis                 matlab.ui.control.UIAxes
        PowerAxis                   matlab.ui.control.UIAxes
        LevelBarAxis                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        SignalData % Signal related common data
        TestSignalData % Test signal related data
        TargetSignalData % Target signal related data
        PlayMode % Playback mode information
        AnalysisData % Analysis related data
    end

    methods (Access = private)

        function results = initializeAudioInput(app)
            results = [];
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            f = msgbox({'Please select the working directory.'; ...
                'Then, please select the audio device.'}, 'Initialization','help');
            app.SignalData.workingDirectory = string(uigetdir);
            fs = 44100;
            testDuration = 20;
            app.SignalData.testDuration = testDuration;
            %bufferLength = 1024;
            recorderObj = audioPlayerRecorder;
            devices = getAudioDevices(recorderObj);
            [deviceID,tf] = listdlg('ListString', ...
                devices, 'SelectionMode','single');
            app.SignalData.audioMode = 'simultaneous';
            if ~tf
                close(f);
                close(app.UIFigure);
            else
                logFileName = "audRespLog" + datestr(now, 30) + ".txt";
                app.SignalData.logFileFid = ...
                    fopen(app.SignalData.workingDirectory + "/" + logFileName, "a");
                app.SignalData.Device = devices{deviceID};
                app.SignalData.recorder = audioPlayerRecorder(fs,"RecorderChannelMapping", [1 2], ...
                    "BitDepth", "24-bit integer", "Device", devices{deviceID});
                bufferLength = get(app.SignalData.recorder, "BufferSize");
                app.SignalData.samplingFrequency = fs;
                app.SignalData.bufferSize = bufferLength;
                app.SignalData.FMcent = 25;
                app.SignalData.Driver = 'simultaneousIO';
                %--- log test condition
                fprintf(app.SignalData.logFileFid, 'Computer: %s\n', computer);
                fprintf(app.SignalData.logFileFid, 'Device: %s\n', devices{deviceID});
                fprintf(app.SignalData.logFileFid, 'Driver: %s\n', app.SignalData.Driver);
                fprintf(app.SignalData.logFileFid, 'Sampling frequency: %8.0f Hz\n', fs);
                switch computer
                    case 'MACI64'
                        aaa = audiodevinfo;
                        nAudioIn = audiodevinfo(1);
                        names = cell(nAudioIn,1);
                        idList = zeros(nAudioIn,1);
                        outnames = cell(nAudioIn,1);
                        outidList = zeros(nAudioIn,1);
                        for ii = 1:nAudioIn
                            names(ii) = {aaa.input(ii).Name};
                            idList(ii) = aaa.input(ii).ID;
                            outnames(ii) = {aaa.output(ii).Name};
                            outidList(ii) = aaa.output(ii).ID;
                        end
                        %%
                        [idx, tf] = listdlg('ListString', names, 'ListSize', [300, 200] ...
                            ,'Name', 'Select input device', 'SelectionMode','single');
                        if ~tf
                            close(f);
                            close(app.UIFigure);
                        else
                        app.SignalData.recorderName = names{idx};
                        app.SignalData.recorderID = idList(idx);
                        fprintf(app.SignalData.logFileFid, 'Recorder: %s\n', names{idx});
                        end
                        [idx, tf] = listdlg('ListString', outnames, 'ListSize', [300, 200] ...
                            ,'Name', 'Select output device', 'SelectionMode','single');
                        if ~tf
                            close(f);
                            close(app.UIFigure);
                        else
                        app.SignalData.playerName = outnames{idx};
                        app.SignalData.playerID = outidList(idx);
                        fprintf(app.SignalData.logFileFid, 'Player: %s\n', names{idx});
                        end
                end
                %---
                nTmpBuffer = 8;
                app.SignalData.nTmpBuffer = nTmpBuffer;
                app.SignalData.running = "off";
                app.SignalData.signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
                timeAxis = ((1:nTmpBuffer * bufferLength) - nTmpBuffer * bufferLength) / fs;
                tt = (0:1/fs:testDuration)' - testDuration;
                app.SignalData.wholeWave = (rand(length(tt), 1) - 0.5) * 0.001;
                fftl = length(timeAxis);
                app.SignalData.fftl = fftl;
                %---- for level bar graph
                app.SignalData.levelBarHandle = bar(app.LevelBarAxis, -20 + 90, ...
                    "FaceColor", [0 1 0]);
                hold(app.LevelBarAxis, "on")
                app.SignalData.peakLineHandle = plot(app.LevelBarAxis, ...
                    [0 2], -10 + [1 1] + 90, 'r', 'linewidth', 3);
                axis(app.LevelBarAxis,[0 2 0 90])
                ylabel(app.LevelBarAxis, 'Input Level (dB rel MSB)')
                set(app.LevelBarAxis, 'ytick', 0:10:90, 'ytickLabel', ...
                    num2str((-90:10:0)'));
                hold(app.LevelBarAxis, "on")
                grid(app.LevelBarAxis, "on")
                set(app.SignalData.levelBarHandle, 'Interruptible','off', ...
                    'BusyAction', 'cancel');
                set(app.SignalData.peakLineHandle, 'Interruptible','off', ...
                    'BusyAction', 'cancel');
                app.SignalData.calibrationLevel = 0;
                %---- prepare loop pink noise
                fftlPink = 2 ^ ceil(log2(fs * 1.4));
                x = pinknoise(fftlPink * 8);
                segment = x((1:fftlPink*2) + fftlPink * 2) .* sqrt(hanning(fftlPink * 2));
                y = x * 0;
                for ii = 0:fftlPink:length(x) - fftlPink * 2
                    y(ii + (1:2*fftlPink)) = y(ii + (1:2*fftlPink)) + segment;
                end
                app.SignalData.loopPink = y(2 * fftlPink + (1:2*fftlPink));
                disp(length(app.SignalData.loopPink));
                %---- set fo menu
                settingStr = getFoSetting('foSetting.txt');
                app.TESTFreqDropDown.Items = cellstr(num2str(settingStr.testFoValues));
                app.VOICEFreqDropDown.Items = cellstr(num2str(settingStr.voicFoValues));
                app.SignalData.FMcent = settingStr.FMcent;
                fprintf(app.SignalData.logFileFid, 'Setting updated: %s\n', datestr(now));
                fprintf(app.SignalData.logFileFid, 'Setting file: %s\n', settingStr.settingFilePath);
                for ii = 1:length(settingStr.inputStr)
                    fprintf(app.SignalData.logFileFid, 'Settings: %s\n', settingStr.inputStr{ii});
                end
                %----
                close(f);
            end
        end

        function results = updateLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            %fftl = app.SignalData.fftl;
            app.SignalData.running = "on";
            nTmpBuffer = app.SignalData.nTmpBuffer;
            signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
            postIdx = 1:(nTmpBuffer - 1) * bufferLength;
            preIdx = postIdx + bufferLength;
            %captureIdx = (nTmpBuffer - 1) * bufferLength + (1:bufferLength);
            app.SignalData.totalOverrun = 0;
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            app.SignalData.overRunCounter = 0;
            %indexWholePre = 1:length(app.SignalData.wholeWave) - bufferLength;
            %tmpOut = zeros(bufferLength, 1);
            switch app.PlayMode.signal
                case 'TEST'
                    testSignal = app.TestSignalData.testSignal;
                case 'VOICET'
                    testSignal = app.TargetSignalData.voiceTargetSignal;
            end
            %testSignal = testSignal / max(abs(testSignal)) * 0.8;
            headLoc = 0;
            app.SignalData.outCopy = app.SignalData.wholeWave;
            %---- speed test
            strtTic = tic;
            updateBufferIndex = 1:bufferLength;
            w = app.TargetSignalData.window;
            winIndexBase = (1:app.TargetSignalData.lWindow) - app.TargetSignalData.lWindow;
            fftlFo = 2^ceil(log2(app.TargetSignalData.lWindow));
            fxFo = (0:fftlFo-1)/fftlFo*fs;
            foVoice = app.TargetSignalData.foVoice;
            idxFoVoice = round(foVoice / fxFo(2)) + 1;
            nTest = 40;
            for ii = 1:nTest
                tmpOut = testSignal(headLoc + updateBufferIndex);
                headLoc = headLoc + bufferLength;
                signalBuffer(postIdx) = signalBuffer(preIdx);
                inputData = tmpOut;
                app.SignalData.wholeWave(headLoc + updateBufferIndex) = ...
                    inputData;
                app.SignalData.outCopy(headLoc + updateBufferIndex) = ...
                    tmpOut;
            end
            speedResult = toc(strtTic) / nTest;
            if speedResult > 0.015
                disp(['This machine is too slow. Realtime spectrogram disabled. ' ...
                    num2str(speedResult * 1000) ' ms'])
            else
                disp(['Update time:' num2str(speedResult * 1000) ' ms'])
            end
            %---- speed test end
            totalLength = 0;
            headLoc = 0;
            timeLeft = length(app.SignalData.wholeWave) / fs;
            app.CountDownLabel.Visible = "on";
            app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
            app.SignalData.outCopy = app.SignalData.wholeWave * 0;
            app.SignalData.totalOverrun = 0;
            wholeWaveLength = length(app.SignalData.wholeWave);
            app.SignalData.wholeWaveLength = wholeWaveLength;
            smoothedFoDevCent = 0;
            while app.SignalData.running == "on" ...
                    && totalLength < wholeWaveLength - bufferLength
                tmpOut = testSignal(headLoc + updateBufferIndex);
                signalBuffer(postIdx) = signalBuffer(preIdx);
                [inputData,numOverrun] = app.SignalData.recorder([tmpOut tmpOut]);
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                app.SignalData.wholeWave(headLoc + updateBufferIndex) = ...
                    inputData(:,1);
                app.SignalData.outCopy(headLoc + updateBufferIndex) = ...
                    inputData(:,2); %tmpOut;
                totalLength = min(wholeWaveLength, totalLength + bufferLength);
                switch app.PlayMode.signal
                    case 'VOICET'
                        idx1 = max(1, winIndexBase + totalLength);
                        idx2 = max(1, winIndexBase + totalLength - 1);
                        specPari = fft([app.SignalData.wholeWave(idx1) .* w, ...
                            app.SignalData.wholeWave(idx2) .* w], fftlFo);
                        foCurrent = angle(specPari(idxFoVoice,1)/specPari(idxFoVoice, 2)) ...
                            /2/pi*fs;
                        foDevCent = max(-400, min(400,1200*log2(foCurrent/foVoice)));
                        smoothedFoDevCent = 0.9 * smoothedFoDevCent + 0.1 * foDevCent;
                        set(app.AnalysisData.pitchHandle, 'ydata', smoothedFoDevCent);
                end
                timeLeft = timeLeft - bufferLength / fs;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
                headLoc = headLoc + bufferLength;
            end
            app.SignalData.recorder([tmpOut tmpOut] * 0);
            app.CountDownLabel.Visible = "off";
            app.SignalData.totalLength = totalLength;
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
        end


        function results = recordLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            %fftl = app.SignalData.fftl;
            memoLength = 2^round(log2(6*fs));
            memoBuffer = zeros(memoLength, 1);
            app.SignalData.totalOverrun = 0;
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            app.SignalData.overRunCounter = 0;
            indexWholePre = 1:memoLength - bufferLength;
            tmpOut = zeros(bufferLength, 1);
            totalLength = 0;
            timeLeft = memoLength / fs;
            app.CountDownLabel.Visible = "on";
            app.CountDownLabel.Enable = "on";
            app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
            drawnow
            while totalLength < memoLength - bufferLength
                [inputData,numOverrun] = app.SignalData.recorder(tmpOut);
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                memoBuffer(indexWholePre) = ...
                    memoBuffer(indexWholePre + bufferLength);
                memoBuffer(end - bufferLength + 1:end) = ...
                    inputData(:,1);
                timeLeft = timeLeft - bufferLength / fs;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
                totalLength = min(memoLength, totalLength + bufferLength);
            end
            app.CountDownLabel.Visible = "off";
            app.SignalData.totalLength = totalLength;
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            fileNameRoot = "audRespMemo" + datestr(now, 30);
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                memoBuffer, app.SignalData.samplingFrequency, "BitsPerSample", 24);
            fprintf(app.SignalData.logFileFid, 'Memo saved: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'File name: %s\n', fileNameRoot + ".wav");
        end

        function results = initializeTestSignal(app)
            %results = [];
            app.TestSignalData = load("testSigBase400ms.mat");
            fo = str2double(app.TESTFreqDropDown.Value);
            fs = app.SignalData.samplingFrequency;
            carrierType = app.SignalTypeDropDown.Value;
            xin = app.SignalData.wholeWave * 0;
            app.TestSignalData.combiID = str2double(app.CombinationIDDropDown.Value);
            output = generateTestFMSignal(app.TestSignalData, fo, ...
                carrierType, app.TestSignalData.combiID, app.SignalData.FMcent, xin);
            switch app.NormalizationDropDown.Value
                case 'PEAK'
                    output.testSignal = ...
                        output.testSignal / max(abs(output.testSignal)) * 0.25;
                case 'TOTAL_RMS'
                    tmpRms = std(output.testSignal(fs:end-fs));
                    output.testSignal = output.testSignal / tmpRms * 10^(-26/20);
                case 'COMPONENT'
                    output.testSignal = output.testSignal * 10^(-30/20);
            end
            app.TestSignalData.testSignal = output.testSignal;
            results = app;
        end

        function results = initializeAnalysisViewer(app)
            results = [];
            testSignal = app.TestSignalData.testSignal;
            fs = app.SignalData.samplingFrequency;
            nData = length(testSignal);
            tx = (1:nData)'/fs;
            %--- Power plot
            app.AnalysisData.sigPowerHandle = ...
                plot(app.PowerAxis, tx, 10*log10(0.8^2) * ones(nData, 1), 'LineWidth', 2);
            hold(app.PowerAxis, 'all');
            app.AnalysisData.refPowerHandle = ...
                plot(app.PowerAxis, tx, 10*log10(0.6^2) * ones(nData, 1), 'LineWidth', 2);
            axis(app.PowerAxis, [0 tx(end) -80 0]);
            xlabel(app.PowerAxis, 'time (x)');
            ylabel(app.PowerAxis, 'level (dB rel. MSB)')
            title(app.PowerAxis, 'signal power')
            %--- Orthogonalized signal plot
            app.AnalysisData.sigOrthHandle = ...
                plot(app.OrthgnlAxis, tx, ones(nData, 1), 'LineWidth', 2);
            hold(app.OrthgnlAxis, 'all');
            app.AnalysisData.refOrthHandle = ...
                plot(app.OrthgnlAxis, tx,  -10 + ones(nData, 1), 'LineWidth', 2);
            app.AnalysisData.bgOrthHandle = ...
                plot(app.OrthgnlAxis, tx,  -5 + ones(nData, 1), 'LineWidth', 2);
            axis(app.OrthgnlAxis, [0 tx(end) -75 75]);
            xlabel(app.OrthgnlAxis, 'time (x)');
            ylabel(app.OrthgnlAxis, 'deviation (cent)')
            title(app.OrthgnlAxis, 'orthogonalized modulation')
            %--- Response plot
            app.AnalysisData.sigRespHandle = ...
                plot(app.RespAxis, tx, ones(nData, 1), 'LineWidth', 2);
            hold(app.RespAxis, 'all');
            app.AnalysisData.refRespHandle = ...
                plot(app.RespAxis, tx,  -10 + ones(nData, 1), 'LineWidth', 2);
            app.AnalysisData.bgRespHandle = ...
                plot(app.RespAxis, tx,  -5 + ones(nData, 1), 'LineWidth', 2);
            axis(app.RespAxis, [0 tx(end) -75 75]);
            xlabel(app.RespAxis, 'time (x)');
            ylabel(app.RespAxis, 'deviation (cent)')
            title(app.RespAxis, 'input and response with background')
            %--- Pitch monitor plot
            plot(app.PitchMonitorAxes, [0 0], [-400 400] ,'k',  ...
                'linewidth', 4);
            hold(app.PitchMonitorAxes, "all")
            for ii = -4:4
                if ii ==  0
                    plot(app.PitchMonitorAxes, [-1 1], [0 0] + ii * 100,'g',  ...
                        'linewidth', 3);
                else
                    plot(app.PitchMonitorAxes, [-0.7 0.7], [0 0] + ii * 100,'k',  ...
                        'linewidth', 1);
                end

            end
            axis(app.PitchMonitorAxes, [-1 1 -410 410], "off");
            app.AnalysisData.pitchHandle = plot(app.PitchMonitorAxes,  ...
                0, 0, 'ro', 'MarkerSize', 20, 'LineWidth', 4);
            disableDefaultInteractivity(app.PitchMonitorAxes);
            set(app.PitchMonitorPanel, 'Visible', 'off');
            app.PitchMonitorAxes.Toolbar.Visible = 'off';
        end

        function results = analysisResponse(app)
            results = [];
            output = foResponseToAuditoryMod(app.SignalData.fullpath, 0);
            signal = output.signal;
            fo = output.fo;
            fs = output.fs;
            fileInfo = output.fileInfo;
            tmp = sscanf(fileInfo.Comment, '%10f');
            if length(tmp) > 1
                foT = tmp(1);
                foV = tmp(1);
                if length(tmp) > 2
                    foV = tmp(3);
                end
            else
                foT = tmp(1);
                foV = tmp(1);
            end
            %----
            [filePath, fileName, fileExt] = fileparts(fileInfo.Filename);
            title(app.PowerAxis, {filePath; ['File name: ' fileName fileExt]; ...
                ['foT:' num2str(foT) ' Hz foV:' num2str(foV) ...
                '  Hz  Type:' fileInfo.Artist ...
                '  CAL:' num2str(output.calibrationConst) ' dB  Set: ' ...
                num2str(output.combination)]})
            %----
            dt = 1/fs;
            cumPower = cumsum(signal .^ 2) * dt;
            tx = (1:length(signal))'/fs;
            powerSq = (interp1(tx, cumPower, tx + 0.5/fo, 'linear', "extrap") ...
                - interp1(tx, cumPower, tx - 0.5/fo, 'linear', "extrap")) / fo;
            set(app.AnalysisData.refPowerHandle, 'ydata', ...
                10*log10(powerSq(:,2)), 'xdata', tx);
            set(app.AnalysisData.sigPowerHandle, 'ydata', ...
                10*log10(powerSq(:,1)), 'xdata', tx);
            axis(app.PowerAxis, [0 tx(end) -120 0])
            %---
            txResp = tx;% - output.fftl/fs/2 + output.fftlSeg/fs*4;
            set(app.AnalysisData.sigOrthHandle, 'ydata', ...
                output.recovered4Signal, 'xdata', txResp);
            set(app.AnalysisData.refOrthHandle, 'ydata', ...
                output.recovered4Pulse, 'xdata', txResp);
            set(app.AnalysisData.bgOrthHandle, 'ydata', ...
                output.backGound, 'xdata', txResp);
            set(app.OrthgnlAxis, 'xlim', [tx(1) tx(end)]);
            %---
            respRefSeg = output.av4Pulse(txResp>4 & txResp < 5);
            txSeg = txResp(txResp>4 & txResp < 5);
            [~, mxIdx] = max(respRefSeg);
            maxTime = txSeg(mxIdx);
            set(app.AnalysisData.sigRespHandle, 'ydata', ...
                output.av4Signal, 'xdata', txResp - maxTime);
            set(app.AnalysisData.refRespHandle, 'ydata', ...
                output.av4Pulse, 'xdata', txResp - maxTime);
            set(app.AnalysisData.bgRespHandle, 'ydata', ...
                output.avBackGround, 'xdata', txResp - maxTime);
            minSig = min(output.av4Signal(txResp> 4.4 & txResp < 7.1));
            maxPulse = max(output.av4Pulse(txResp>4.4 & txResp < 7.1));
            axis(app.RespAxis, [[4.4 7.1]- maxTime min(-5, minSig - 3) maxPulse + 5]);
        end

        function results = initializeTargetSignal(app)
            app.TargetSignalData = load("testSigBase400ms.mat");
            %fo = str2double(app.FRQEditField.Value);
            fo = str2double(app.VOICEFreqDropDown.Value);
            fs = app.SignalData.samplingFrequency;
            carrierType = app.SignalTypeDropDown.Value;
            xin = app.SignalData.wholeWave * 0;
            app.TargetSignalData.combiID = str2double(app.CombinationIDDropDown.Value);
            output = generateTestFMSignal(app.TargetSignalData, fo, ...
                carrierType, app.TargetSignalData.combiID, 0, xin);
            switch app.NormalizationDropDown.Value
                case 'PEAK'
                    output.testSignal = ...
                        output.testSignal / max(abs(output.testSignal)) * 0.25;
                case 'TOTAL_RMS'
                    tmpRms = std(output.testSignal(fs:end-fs));
                    output.testSignal = output.testSignal / tmpRms * 10^(-26/20);
                case 'COMPONENT'
                    output.testSignal = output.testSignal * 10^(-30/20);
            end
            app.TargetSignalData.voiceTargetSignal = output.testSignal;
            w = sixtermCos(round(12*fs/fo));
            app.TargetSignalData.window = w/sum(w);
            app.TargetSignalData.lWindow = length(w);
            app.TargetSignalData.foVoice = fo;

            results = app;
        end

        function results = disableAllInteractivity(app)
            results = [];
            app.LevelBarAxis.Toolbar.Visible = 'off';
            app.RespAxis.Toolbar.Visible = 'off';
            app.OrthgnlAxis.Toolbar.Visible = 'off';
            app.PowerAxis.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.LevelBarAxis);
            disableDefaultInteractivity(app.RespAxis);
            disableDefaultInteractivity(app.OrthgnlAxis);
            disableDefaultInteractivity(app.PowerAxis);
        end

        function results = enableAllInteractivity(app)
            results = [];
            enableDefaultInteractivity(app.LevelBarAxis);
            enableDefaultInteractivity(app.RespAxis);
            enableDefaultInteractivity(app.OrthgnlAxis);
            enableDefaultInteractivity(app.PowerAxis);
            app.LevelBarAxis.Toolbar.Visible = 'on';
            app.RespAxis.Toolbar.Visible = 'on';
            app.OrthgnlAxis.Toolbar.Visible = 'on';
            app.PowerAxis.Toolbar.Visible = 'on';
        end


        function results = playLoopFcn(app)
            pause(0.1)
            %varInput = 0;
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            baseIdx = 1:bufferLength;
            totalUnderRun = 0;
            initialPoint = 1;
            timeLeft = app.SignalData.totalLength/fs;
            app.CountDownLabel.Visible = "on";
            while app.SignalData.running == "on" ...
                    && initialPoint < app.SignalData.totalLength - bufferLength
                tmpOut = app.SignalData.wholeWave(initialPoint + baseIdx);
                [~, numUnderRun] = app.SignalData.recorder(tmpOut);
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
                timeLeft = timeLeft - bufferLength / fs;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
            end
            app.CountDownLabel.Visible = "off";
            pause(0.1)
            results = app;
        end
        
        function results = updateLoopForMac(app)
            results = [];
            fs = app.SignalData.samplingFrequency;
            recorderID = app.SignalData.recorderID;
            recorder = audiorecorder(fs,24,2,recorderID);
            testSignal = app.TestSignalData.testSignal;
            testSignal = testSignal / max(abs(testSignal)) * 0.8;
            testSignal = [testSignal(:,1) testSignal(:,1)];
            wholeWaveLength = length(app.SignalData.wholeWave);
            tmpFileName = "testSignalTmp.wav";
            audiowrite(tmpFileName, testSignal, fs, "BitsPerSample", 24);
            testDuration = wholeWaveLength / fs;
            player = audioplayer(testSignal, fs, 24, app.SignalData.playerID);
            record(recorder,testDuration + 0.5);
            play(player);
            timeLeft = testDuration;
            app.CountDownLabel.Visible = "on";
            while timeLeft > 0
                pause(0.05);
                timeLeft = timeLeft - 0.05;
                app.CountDownLabel.Text = num2str(timeLeft, "%5.2f");
                drawnow limitrate
            end
            app.CountDownLabel.Visible = "off";
            y = getaudiodata(recorder);
            app.SignalData.wholeWave = y(1:wholeWaveLength, 1);
            app.SignalData.outCopy = y(1:wholeWaveLength, 2);
            app.SignalData.totalLength = wholeWaveLength;
            stop(recorder);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAudioInput(app);
            app = initializeTestSignal(app);
            app = initializeTargetSignal(app);
            initializeAnalysisViewer(app);
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'A/D started: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Test signal: %s\n', app.SignalTypeDropDown.Value);
            fprintf(app.SignalData.logFileFid, 'Test fo: %s\n', app.TESTFreqDropDown.Value);
            app.STARTButton.Enable = 'off';
            app.VoicecheckButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.MEMO6sButton.Enable = 'off';
            %disableDefaultInteractivity(app.LevelBarAxis);
            disableAllInteractivity(app)
            app.TestsignalstartButton.Enable = 'off';
            app.PlayMode.signal = 'TEST';
            set(app.RightPanel,'enable', 'off');
            app.STOPButton.Enable = 'on';
            drawnow
            switch computer
                case 'MACI64'
                    updateLoopForMac(app);
                otherwise
                    updateLoop(app);
            end
            set(app.RightPanel,'enable', 'on');
            fprintf(app.SignalData.logFileFid, 'A/D stopped: %s\n', datestr(now));
            %enableDefaultInteractivity(app.LevelBarAxis);
            enableAllInteractivity(app)
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.VoicecheckButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            if app.SignalData.totalLength >= app.SignalData.wholeWaveLength - app.SignalData.bufferSize
                app.SAVEButton.Enable = 'on';
            end
            app.MEMO6sButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
            drawnow
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.SignalData.running = "off";
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            disp("BYE...")
            if isfield(app.SignalData, 'logFileFid')
                fclose(app.SignalData.logFileFid);
            end
            pause(0.5);
            close(app.UIFigure);
        end

        % Button pushed function: TestsignalstartButton
        function TestsignalstartButtonPushed(app, event)
            disableDefaultInteractivity(app.LevelBarAxis);
            bufferLength = app.SignalData.bufferSize;
            baseIdx = 1:bufferLength;
            initialPoint = 0;
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Test Signal started: %s\n', datestr(now));
            totalUnderRun = 0;
            app.PLAYButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.dBButton70.Enable = 'on';
            app.dBButton80.Enable = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            pause(0.1)
            varInput = 0;
            sigLength = length(app.SignalData.loopPink);
            app.SignalData.loopBuffer = zeros(sigLength, 1);
            preIdx = 1:sigLength - bufferLength;
            postIdx = (1:sigLength - bufferLength) + bufferLength;
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            while app.SignalData.running == "on"
                app.SignalData.loopBuffer(preIdx) = app.SignalData.loopBuffer(postIdx);
                tmpOut = app.SignalData.loopPink(initialPoint + baseIdx);
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        [audioIn, numUnderRun] = app.SignalData.recorder(tmpOut);
                    case 'independent'
                        [audioIn, numUnderRun1] = app.SignalData.recorder();
                        numUnderRun = app.SignalData.player(tmpOut) + numUnderRun1;
                end
                app.SignalData.loopBuffer(sigLength - bufferLength + baseIdx) = audioIn(:,1);
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
                varInput = varInput + std(audioIn(:,1)) .^ 2;
                initialPoint = rem(initialPoint, sigLength);
                set(app.SignalData.levelBarHandle, 'ydata', ...
                    20 * log10(std(app.SignalData.loopBuffer(pickerIdx))) + 90);
                set(app.SignalData.peakLineHandle, 'ydata', ...
                    20 * log10(max(abs(app.SignalData.loopBuffer(pickerIdx)))) * [1, 1] + 90);
                drawnow limitrate
            end
            pause(0.1);
            release(app.SignalData.recorder);
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.RUNLamp.Visible = 'off';
            enableDefaultInteractivity(app.LevelBarAxis);
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton80
        function dBButton80Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 80 - signalDB;
            app.Lamp80rab.Visible = 'on';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            fprintf(app.SignalData.logFileFid, 'Calibration 80dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton70
        function dBButton70Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 70 - signalDB;
            app.Lamp70dB.Visible = 'on';
            fprintf(app.SignalData.logFileFid, 'Calibration 70dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.TestsignalstartButton.Enable = 'on';
            app.RESETButton.Enable = 'off';
            app.SignalData.calibrationLevel = 0;
            app.Lamp80rab.Visible = 'off';
            app.Lamp70dB.Visible = 'off';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            %initialPoint = max(0, length(app.SignalData.wholeWave) ...
            %    - app.SignalData.totalLength);
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Played back: %s\n', datestr(now));
            app.PLAYButton.Enable = 'off';
            app.VoicecheckButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.STOPButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            playLoopFcn(app);
            if 1 == 2
                pause(0.1)
                %varInput = 0;
                bufferLength = app.SignalData.bufferSize;
                baseIdx = 1:bufferLength;
                totalUnderRun = 0;
                initialPoint = 1;
                while app.SignalData.running == "on" ...
                        && initialPoint < app.SignalData.totalLength - bufferLength
                    tmpOut = app.SignalData.wholeWave(initialPoint + baseIdx);
                    [~, numUnderRun] = app.SignalData.recorder(tmpOut);
                    initialPoint = initialPoint + bufferLength;
                    totalUnderRun = totalUnderRun + numUnderRun;
                end
                pause(0.1)
            end
            release(app.SignalData.recorder);
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.VoicecheckButton.Enable = 'on';
            if app.SignalData.totalLength >= app.SignalData.wholeWaveLength - app.SignalData.bufferSize
                switch app.PlayMode.signal
                    case 'TEST'
                        app.SAVEButton.Enable = 'on';
                end
            end
            app.QUITButton.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.RUNLamp.Visible = 'off';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            fileNameRoot = "audResp" + datestr(now, 30);
            %initialPoint = max(0, length(app.SignalData.wholeWave) ...
            %    - app.SignalData.totalLength);
            initialPoint = 1;
            outData = [app.SignalData.wholeWave(initialPoint + 1:end), ...
                app.SignalData.outCopy(initialPoint + 1:end)];
            commentText = [app.TESTFreqDropDown.Value ' ' num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                '  ' app.VOICEFreqDropDown.Value ' ' num2str(app.SignalData.FMcent)];
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                outData, app.SignalData.samplingFrequency, "BitsPerSample", 24, ...
                "Comment",commentText,"Artist", app.SignalTypeDropDown.Value, ...
                "Title", app.CombinationIDDropDown.Value);
            fprintf(app.SignalData.logFileFid, 'Data saved: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'File name: %s\n', fileNameRoot + ".wav");
            app.SAVEButton.Enable = 'off';
            app.SignalData.fullpath = app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav";
            set(app.LeftPanel,'enable', 'off');
            set(app.RightPanel,'enable', 'off');
            drawnow
            analysisResponse(app);
            set(app.LeftPanel,'enable', 'on');
            set(app.RightPanel,'enable', 'on');
            drawnow
        end

        % Button pushed function: MEMO6sButton
        function MEMO6sButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'Memo started: %s\n', datestr(now));
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.MEMO6sButton.Enable = 'off';
            app.TestsignalstartButton.Enable = 'off';
            recordLoop(app);
            fprintf(app.SignalData.logFileFid, 'Memo stopped: %s\n', datestr(now));
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.MEMO6sButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            %value = app.SignalTypeDropDown.Value;
            initializeTestSignal(app);
            initializeTargetSignal(app);
        end

        % Button pushed function: READ_FileButton
        function READ_FileButtonPushed(app, event)
            [file,path] = uigetfile('audResp*.wav');
            app.SignalData.fullpath = [path file];
            set(app.LeftPanel,'enable', 'off');
            set(app.RightPanel,'enable', 'off');
            drawnow
            analysisResponse(app);
            set(app.LeftPanel,'enable', 'on');
            set(app.RightPanel,'enable', 'on');
            drawnow
        end

        % Button pushed function: REPORTButton
        function REPORTButtonPushed(app, event)
            %exportgraphics(app.RightPanel, 'test.jpg')
            set(app.Label, 'Text', datestr(now))
            fileName = char(app.SignalData.fullpath);
            fileName = [fileName(1:end-4) 'Cpy.jpg'];
            exportapp(app.UIFigure, fileName);
            set(app.Label, 'Text', ' ')
        end

        % Value changed function: CombinationIDDropDown
        function CombinationIDDropDownValueChanged(app, event)
            %value = app.CombinationIDDropDown.Value;
            initializeTestSignal(app);
            initializeTargetSignal(app);
        end

        % Button pushed function: VoicecheckButton
        function VoicecheckButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'Target check started: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Target signal: %s\n', app.SignalTypeDropDown.Value);
            fprintf(app.SignalData.logFileFid, 'Voice Target fo: %s\n', app.VOICEFreqDropDown.Value);
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.VoicecheckButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.MEMO6sButton.Enable = 'off';
            %disableDefaultInteractivity(app.LevelBarAxis);
            disableAllInteractivity(app)
            app.TestsignalstartButton.Enable = 'off';
            app.PlayMode.signal = 'VOICET';
            set(app.RightPanel,'enable', 'off');
            set(app.PitchMonitorPanel, 'Visible', 'on');
            updateLoop(app);
            set(app.PitchMonitorPanel, 'Visible', 'off');
            fprintf(app.SignalData.logFileFid, 'Target check stopped: %s\n', datestr(now));
            %enableDefaultInteractivity(app.LevelBarAxis);
            enableAllInteractivity(app)
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.VoicecheckButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            %app.SAVEButton.Enable = 'on';
            app.MEMO6sButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
            set(app.RightPanel,'enable', 'on');
        end

        % Value changed function: TESTFreqDropDown
        function TESTFreqDropDownValueChanged(app, event)
            %value = app.TESTFreqDropDown.Value;
            initializeTestSignal(app);
            initializeTargetSignal(app);
        end

        % Value changed function: VOICEFreqDropDown
        function VOICEFreqDropDownValueChanged(app, event)
            %value = app.VOICEFreqDropDown.Value;
            initializeTestSignal(app);
            initializeTargetSignal(app);
        end

        % Button pushed function: UpdatesettingButton
        function UpdatesettingButtonPushed(app, event)
            settingFilePath = uigetfile('*.txt');
            if settingFilePath ~= 0
                settingStr = getFoSetting(settingFilePath);
                settingStr.settingFilePath = settingFilePath;
                app.TESTFreqDropDown.Items = cellstr(num2str(settingStr.testFoValues));
                app.VOICEFreqDropDown.Items = cellstr(num2str(settingStr.voicFoValues));
                app.SignalData.FMcent = settingStr.FMcent;
                fprintf(app.SignalData.logFileFid, 'Setting updated: %s\n', datestr(now));
                fprintf(app.SignalData.logFileFid, 'Setting file: %s\n', settingStr.settingFilePath);
                for ii = 1:length(settingStr.inputStr)
                    fprintf(app.SignalData.logFileFid, 'Settings: %s\n', settingStr.inputStr{ii});
                end
                initializeTestSignal(app);
                initializeTargetSignal(app);
            end
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            %value = app.NormalizationDropDown.Value;
            fprintf(app.SignalData.logFileFid, 'Normalization changed: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Normalization: %s\n', app.NormalizationDropDown.Value);
            initializeTestSignal(app);
            initializeTargetSignal(app);
        end

        % Button pushed function: SAVEtestsignalButton
        function SAVEtestsignalButtonPushed(app, event)
            fprintf(app.SignalData.logFileFid, 'Save test signal button pushed: %s\n', datestr(now));
            fileNameRoot = "testSignal" + datestr(now, 30);
            outData = app.TestSignalData.testSignal;
            commentText = [app.TESTFreqDropDown.Value ' ' '0.00' ...
                '  ' app.VOICEFreqDropDown.Value ' ' num2str(app.SignalData.FMcent)];
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                outData, app.SignalData.samplingFrequency, "BitsPerSample", 24, ...
                "Comment",commentText,"Artist", app.SignalTypeDropDown.Value, ...
                "Title", app.CombinationIDDropDown.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {805, 805};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.SAVEButton = uibutton(app.LeftPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.PLAYButton = uibutton(app.LeftPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.RUNLampLabel = uilabel(app.LeftPanel);
            app.RUNLampLabel.Text = 'RUN';
            app.RUNLamp = uilamp(app.LeftPanel);
            app.RUNLamp.Visible = 'off';
            app.OVRLampLabel = uilabel(app.LeftPanel);
            app.OVRLampLabel.Text = 'OVR';
            app.OVRLamp = uilamp(app.LeftPanel);
            app.OVRLamp.Visible = 'off';
            app.AuditoryResponseTesterCAPRICEPLabel = uilabel(app.LeftPanel);
            app.AuditoryResponseTesterCAPRICEPLabel.Text = {'Auditory Response Tester'; '(CAPRICEP)'};
            app.AboutButton = uibutton(app.LeftPanel, 'push');
            app.AboutButton.Text = 'About';
            app.CALIBRATIONPanel = uipanel(app.LeftPanel);
            app.CALIBRATIONPanel.Title = 'CALIBRATION';
            app.TestsignalstartButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.TestsignalstartButton.ButtonPushedFcn = createCallbackFcn(app, @TestsignalstartButtonPushed, true);
            app.TestsignalstartButton.Text = 'Test signal start';
            app.dBButton70 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton70.ButtonPushedFcn = createCallbackFcn(app, @dBButton70Pushed, true);
            app.dBButton70.Enable = 'off';
            app.dBButton70.Text = '70dB';
            app.dBButton80 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton80.ButtonPushedFcn = createCallbackFcn(app, @dBButton80Pushed, true);
            app.dBButton80.Enable = 'off';
            app.dBButton80.Text = '80dB';
            app.Lamp70dB = uilamp(app.CALIBRATIONPanel);
            app.Lamp70dB.Visible = 'off';
            app.Lamp80rab = uilamp(app.CALIBRATIONPanel);
            app.Lamp80rab.Visible = 'off';
            app.RESETButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.CalLevelLabel = uilabel(app.CALIBRATIONPanel);
            app.CalLevelLabel.Text = '0';
            app.CountDownLabel = uilabel(app.LeftPanel);
            app.CountDownLabel.Enable = 'off';
            app.CountDownLabel.Visible = 'off';
            app.CountDownLabel.Text = '0';
            app.MEMO6sButton = uibutton(app.LeftPanel, 'push');
            app.MEMO6sButton.ButtonPushedFcn = createCallbackFcn(app, @MEMO6sButtonPushed, true);
            app.MEMO6sButton.Text = 'MEMO5s';
            app.TEST_SIGNALPanel = uipanel(app.LeftPanel);
            app.TEST_SIGNALPanel.Title = 'TEST_SIGNAL';
            app.SignalTypeDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.SignalTypeDropDown.Items = {'SINE', 'SINES', 'MFND', 'MFNDH'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'SINE';
            app.UpdatesettingButton = uibutton(app.TEST_SIGNALPanel, 'push');
            app.UpdatesettingButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatesettingButtonPushed, true);
            app.UpdatesettingButton.Text = 'Update setting';
            app.CombinationIDDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.CombinationIDDropDownLabel.Text = 'CombinationID';
            app.CombinationIDDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.CombinationIDDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.CombinationIDDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationIDDropDownValueChanged, true);
            app.CombinationIDDropDown.Value = '1';
            app.TESTFreqDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.TESTFreqDropDownLabel.Text = 'TEST Freq';
            app.TESTFreqDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.TESTFreqDropDown.Items = {'120', '130', '140', ''};
            app.TESTFreqDropDown.ValueChangedFcn = createCallbackFcn(app, @TESTFreqDropDownValueChanged, true);
            app.TESTFreqDropDown.Value = '120';
            app.VOICEFreqDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.VOICEFreqDropDownLabel.Text = 'VOICE Freq';
            app.VOICEFreqDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.VOICEFreqDropDown.Items = {'120', '130', '140', ''};
            app.VOICEFreqDropDown.ValueChangedFcn = createCallbackFcn(app, @VOICEFreqDropDownValueChanged, true);
            app.VOICEFreqDropDown.Value = '120';
            app.NormalizationDropDownLabel = uilabel(app.TEST_SIGNALPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.TEST_SIGNALPanel);
            app.NormalizationDropDown.Items = {'PEAK', 'TOTAL_RMS', 'COMPONENT'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'PEAK';
            app.SAVEtestsignalButton = uibutton(app.TEST_SIGNALPanel, 'push');
            app.SAVEtestsignalButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEtestsignalButtonPushed, true);
            app.SAVEtestsignalButton.Text = 'SAVE test signal';
            app.VoicecheckButton = uibutton(app.LeftPanel, 'push');
            app.VoicecheckButton.ButtonPushedFcn = createCallbackFcn(app, @VoicecheckButtonPushed, true);
            app.VoicecheckButton.Text = 'Voice check';
            app.PitchMonitorPanel = uipanel(app.LeftPanel);
            app.PitchMonitorPanel.Title = 'Pitch Monitor';
            app.PitchMonitorAxes = uiaxes(app.PitchMonitorPanel);
            app.PitchMonitorAxes.XTick = [];
            app.PitchMonitorAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.LevelBarAxis = uiaxes(app.RightPanel);
            title(app.LevelBarAxis, 'Level')
            ylabel(app.LevelBarAxis, 'Y')
            zlabel(app.LevelBarAxis, 'Z')
            app.LevelBarAxis.XTick = [];
            app.LevelBarAxis.XTickLabel = '';
            app.PowerAxis = uiaxes(app.RightPanel);
            title(app.PowerAxis, 'd')
            xlabel(app.PowerAxis, 'X')
            ylabel(app.PowerAxis, 'Y')
            zlabel(app.PowerAxis, 'Z')
            app.OrthgnlAxis = uiaxes(app.RightPanel);
            title(app.OrthgnlAxis, 'd')
            xlabel(app.OrthgnlAxis, 'X')
            ylabel(app.OrthgnlAxis, 'Y')
            zlabel(app.OrthgnlAxis, 'Z')
            app.RespAxis = uiaxes(app.RightPanel);
            title(app.RespAxis, 'd')
            xlabel(app.RespAxis, 'X')
            ylabel(app.RespAxis, 'Y')
            zlabel(app.RespAxis, 'Z')
            app.READ_FileButton = uibutton(app.RightPanel, 'push');
            app.READ_FileButton.ButtonPushedFcn = createCallbackFcn(app, @READ_FileButtonPushed, true);
            app.READ_FileButton.Text = 'READ_File';
            app.REPORTButton = uibutton(app.RightPanel, 'push');
            app.REPORTButton.ButtonPushedFcn = createCallbackFcn(app, @REPORTButtonPushed, true);
            app.REPORTButton.Text = 'REPORT';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auditoryResponseTesterChor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end