classdef realTimeTester < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARTester                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CalibrationPanel             matlab.ui.container.Panel
        RLamp                        matlab.ui.control.Lamp
        RLampLabel                   matlab.ui.control.Label
        LLamp                        matlab.ui.control.Lamp
        LLampLabel                   matlab.ui.control.Label
        RESETButton_R                matlab.ui.control.Button
        A70dBButton_R                matlab.ui.control.Button
        A80dBButton_R                matlab.ui.control.Button
        RESETButton                  matlab.ui.control.Button
        A80dBButton                  matlab.ui.control.Button
        A70dBButton                  matlab.ui.control.Button
        OfflinetoolsPanel            matlab.ui.container.Panel
        DriverLabel                  matlab.ui.control.Label
        DeviceLabel                  matlab.ui.control.Label
        SRofflnDropDown              matlab.ui.control.DropDown
        SRofflnDropDownLabel         matlab.ui.control.Label
        ACTIONDropDown               matlab.ui.control.DropDown
        ACTIONDropDownLabel          matlab.ui.control.Label
        ResultsINSPECTORButton       matlab.ui.control.Button
        INPUTDropDown                matlab.ui.control.DropDown
        INPUTDropDownLabel           matlab.ui.control.Label
        ROUTMEASUREButton            matlab.ui.control.Button
        LOUTMEASUREButton            matlab.ui.control.Button
        VoiceMemoCounterLabel        matlab.ui.control.Label
        VOICEMEMOButton              matlab.ui.control.Button
        poweredbyCAPRICEPLabel       matlab.ui.control.Label
        AcousticresponsetesterLabel  matlab.ui.control.Label
        QUITButton                   matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        RECLamp                      matlab.ui.control.Lamp
        RECLampLabel                 matlab.ui.control.Label
        DISPLAYDropDown              matlab.ui.control.DropDown
        DISPLAYDropDownLabel         matlab.ui.control.Label
        panSlider                    matlab.ui.control.Slider
        panSliderLabel               matlab.ui.control.Label
        RespmsDropDown               matlab.ui.control.DropDown
        RespmsDropDownLabel          matlab.ui.control.Label
        SmoothingDropDown            matlab.ui.control.DropDown
        SmoothingDropDownLabel       matlab.ui.control.Label
        magSlider                    matlab.ui.control.Slider
        magSliderLabel               matlab.ui.control.Label
        DispBiasDropDown             matlab.ui.control.DropDown
        DispBiasDropDownLabel        matlab.ui.control.Label
        RecOrCounter                 matlab.ui.control.Label
        PlayOrCounter                matlab.ui.control.Label
        LevelAxes                    matlab.ui.control.UIAxes
        FreqRespAxes                 matlab.ui.control.UIAxes
        PowerDecayAxes               matlab.ui.control.UIAxes
        ResponseAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CommonSignal % Signal base
        PlotAttributes % Visualization base
        memoData % for audio memo
    end
    
    methods (Access = private)
        
        function results = updateAudioLoop(app)
            fs = app.CommonSignal.samplingFreq;
            results = fs;
            repetitionSize = app.CommonSignal.repetitionSize;
            updateSize = app.CommonSignal.updateSize;
            tmpIndex = 1:repetitionSize * 8;
            updateIndex = 1:updateSize;
            app.CommonSignal.playRec = audioPlayerRecorder(...
                fs,'BitDepth','24-bit integer', 'Device', app.CommonSignal.DeviceName, ...
                'PlayerChannelMapping', [1 2], 'RecorderChannelMapping', [1 2]);
            app.CommonSignal.readBuffer = zeros(repetitionSize * 8, 1);
            recoveredBuffer = zeros(repetitionSize * 8, 3);
            syncBuffer = zeros(repetitionSize * 8, 2);
            xrTSP = app.CommonSignal.xTSPBaseSh(end:-1:1, 1:3);
            xrTSP = [xrTSP; zeros(updateSize, 3)];
            shiftedIndex = circshift(tmpIndex, -updateSize);
            refSignal = fftfilt(xrTSP(:, 1:2), app.CommonSignal.loopSignal(:, 1) ...
                + app.CommonSignal.loopSignal(:, 2));
            refMax = max(refSignal(:, 1) + refSignal(:, 2));
            playOrCounter = 0;
            recOrCounter = 0;
            fx = app.PlotAttributes.frequencyAxis;
            fxU = fx * 2^(1/6);
            fxL = fx * 2^(-1/6);
            bw = fxU - fxL;
            bw(1) = bw(2);
            tmpHead = 0;
            tmpMax = round(updateSize / 1024);
            inputTmp = zeros(updateSize, 1);
            while app.CommonSignal.running
                tmpIndex = circshift(tmpIndex, -1024);
                outSignal = app.CommonSignal.loopSignal(tmpIndex(updateIndex), :);
                [recordTmp,outOverrun,overrun] = ...
                    app.CommonSignal.playRec(outSignal(1:1024, :));
                playOrCounter = playOrCounter + outOverrun;
                switch app.DISPLAYDropDown.Value
                    case 'R-CH'
                        inputTmp(tmpHead * 1024 + (1:1024)) = recordTmp(:, 2);
                    case 'L-CH'
                        inputTmp(tmpHead * 1024 + (1:1024)) = recordTmp(:, 1);
                end
                tmpHead = rem(tmpHead + 1, tmpMax);
                if tmpHead == 0
                    recOrCounter = recOrCounter + overrun;
                    app.CommonSignal.readBuffer = app.CommonSignal.readBuffer(shiftedIndex);
                    if outOverrun > 0
                        tmpIndex = circshift(tmpIndex, -outOverrun);
                    end
                    recoveredBuffer = recoveredBuffer(shiftedIndex, :);
                    syncBuffer = syncBuffer(shiftedIndex, :);
                    recoveredBuffer(31 * updateSize + updateIndex, :) = 0;
                    syncBuffer(31 * updateSize + updateIndex, :) = 0;
                    %app.CommonSignal.readBuffer(31 * updateSize + updateIndex) = inputTmp;
                    app.CommonSignal.readBuffer(updateIndex + end - updateSize) = inputTmp;
                    recoveredTmp = filter(app.CommonSignal.pinkLPC, 1, fftfilt(inputTmp, xrTSP));
                    ovaIdx = 23 * updateSize + (1:9*updateSize);
                    recoveredBuffer(ovaIdx, :) = recoveredBuffer(ovaIdx, :) ...
                        + recoveredTmp;
                    rsyncTmp = fftfilt(outSignal(:, 1) + outSignal(:, 2), ...
                        xrTSP(:, 1:2));
                    syncBuffer(ovaIdx, :) = syncBuffer(ovaIdx, :) ...
                        + rsyncTmp;
                    %levelCheckSignal = app.CommonSignal.readBuffer(7*repetitionSize + (1:repetitionSize));
                    levelCheckSignal = app.CommonSignal.readBuffer(end - 2 * repetitionSize + (1:2 *repetitionSize));
                    dispBias = str2double(app.DispBiasDropDown.Value);
                    nBias = double(rem(playOrCounter + dispBias * updateSize, ...
                        repetitionSize));
                    yDisp1 = recoveredBuffer((1:repetitionSize) + nBias, 1) ...
                        + recoveredBuffer(repetitionSize + (1:repetitionSize) + nBias, 1);
                    yDisp2 = recoveredBuffer((1:repetitionSize) + nBias, 2) ...
                        - recoveredBuffer(repetitionSize + (1:repetitionSize) + nBias, 2);
                    yDisp3 = recoveredBuffer((1:repetitionSize) + nBias, 3) ...
                        + recoveredBuffer(repetitionSize + (1:repetitionSize) + nBias, 3) ...
                        - recoveredBuffer(2 * repetitionSize + (1:repetitionSize) + nBias, 3) ...
                        - recoveredBuffer(3 * repetitionSize + (1:repetitionSize) + nBias, 3);
                    dBlevel = 20*log10(std(levelCheckSignal));
                    dBpeak = 20*log10(max(abs(levelCheckSignal)));
                    app.PlayOrCounter.Text = "PlyOr: " + num2str(playOrCounter, '%10.0f');
                    app.RecOrCounter.Text = "RecOr: " + num2str(recOrCounter, '%10.0f');
                    set(app.PlotAttributes.levelHandle, 'ydata', dBlevel + 100);
                    set(app.PlotAttributes.peakHandle, 'ydata', dBpeak + 100 + [0 0]);
                    tmpMaxSync = max(syncBuffer(updateIndex, 1) + syncBuffer(updateIndex, 2));
                    if  tmpMaxSync > 0.7 * refMax
                        set(app.PlotAttributes.respHandle1, 'ydata', yDisp1/max(abs(yDisp1)));
                        set(app.PlotAttributes.respHandle2, 'ydata', yDisp2/max(abs(yDisp2)));
                        pw1 = abs(fft(yDisp1)) .^ 2;
                        pw2 = abs(fft(yDisp2)) .^ 2;
                        pw3 = abs(fft(yDisp3)) .^ 2;
                        switch app.SmoothingDropDown.Value
                            case 'ON'
                                cumPw1 = cumsum(pw1);
                                cumPw2 = cumsum(pw2);
                                cumPw3 = cumsum(pw3);
                                pw1 = (interp1(fx, cumPw1, fxU, 'linear', 'extrap') ...
                                    - interp1(fx, cumPw1, fxL, 'linear', 'extrap')) ./ bw / bw(1);
                                pw2 = (interp1(fx, cumPw2, fxU, 'linear', 'extrap') ...
                                    - interp1(fx, cumPw2, fxL, 'linear', 'extrap')) ./ bw / bw(1);
                                pw3 = (interp1(fx, cumPw3, fxU, 'linear', 'extrap') ...
                                    - interp1(fx, cumPw3, fxL, 'linear', 'extrap')) ./ bw / bw(1);
                        end
                        totalPwr = sum(pw1 + pw2 + pw3) / repetitionSize;
                        set(app.PlotAttributes.freqRespHandle1, 'ydata', 10*log10(pw1 / totalPwr));
                        set(app.PlotAttributes.freqRespHandle2, 'ydata', 10*log10(pw2 / totalPwr));
                        set(app.PlotAttributes.freqRespHandle3, 'ydata', 10*log10(pw3 / totalPwr));
                        w = app.PlotAttributes.powerSmoother;
                        powerResp1 = 10 * log10(abs(fftfilt(w, yDisp1 .^ 2)));
                        powerResp2 = 10 * log10(abs(fftfilt(w, yDisp2 .^ 2)));
                        set(app.PlotAttributes.powerRespHandle1, 'ydata', powerResp1 ...
                            - max(powerResp1));
                        set(app.PlotAttributes.powerRespHandle2, 'ydata', powerResp2 ...
                            - max(powerResp2));
                    end
                end
                drawnow limitrate
            end
            release(app.CommonSignal.playRec);
        end
        
        function results = initializeTestSignal(app)
            if ~isfield(app.CommonSignal, 'outPath')
                app.CommonSignal.f = msgbox({'Please select the working directory.'; ...
                    'Then, please select the audio device.'}, 'Initialization','help');
                assignDataDirectory(app);
            end
            if ~isfield(app.CommonSignal, 'calibrationConst')
                app.CommonSignal.calibrationConst = 0;
            end
            if ~isfield(app.CommonSignal, 'calibrationConst_R')
                app.CommonSignal.calibrationConst_R = 0;
            end
            if ~isfield(app.CommonSignal, 'DeviceName')
                devices = getAudioDevices(audioPlayerRecorder);
                [deviceID,tf] = listdlg('ListString',devices, 'SelectionMode','single');
                if tf
                    app.CommonSignal.DeviceName = devices{deviceID};
                    app.DeviceLabel.Text = devices{deviceID};
                    fs = 44100;
                    %app.CommonSignal.Reader = audioDeviceReader(...
                    %    fs,'BitDepth','24-bit integer', 'Device', devices{deviceID});
                    app.CommonSignal.Reader = audioPlayerRecorder(fs,"RecorderChannelMapping", 1, ...
                    "BitDepth", "24-bit integer", "Device", devices{deviceID});
                    app.DriverLabel.Text = 'simultaneousIO'; %get(app.CommonSignal.Reader,"Driver");
                else
                    close(app.ARTester);
                end
            end
            value = app.RespmsDropDown.Value;
            app.CommonSignal.periodText = value;
            switch value
                case '100'
                    tmp = load("setCAPRICEP100msRc.mat");
                    app.CommonSignal.updateSize = 1024;
                    app.CommonSignal.repetitionSize = 4096;
                    app.CommonSignal.signalSize = 8192;
                case '200'
                    tmp = load("setCAPRICEP200msRc.mat");
                    app.CommonSignal.updateSize = 2048;
                    app.CommonSignal.repetitionSize = 8192;
                    app.CommonSignal.signalSize = 16384;
                case '400'
                    tmp = load("setCAPRICEP400msRc.mat");
                    app.CommonSignal.updateSize = 4096;
                    app.CommonSignal.repetitionSize = 16384;
                    app.CommonSignal.signalSize = 32768;
                case '800'
                    tmp = load("setCAPRICEP800msRc.mat");
                    app.CommonSignal.updateSize = 4096*2;
                    app.CommonSignal.repetitionSize = 16384*2;
                    app.CommonSignal.signalSize = 32768*2;
            end
            app.CommonSignal.samplingFreq = tmp.fs;
            app.CommonSignal.weightFilt = weightingFilter('A-weighting' ,tmp.fs);
            repetitionSize = app.CommonSignal.repetitionSize;
            signalSize = app.CommonSignal.signalSize;
            app.CommonSignal.running = 0;
            fftl = size(tmp.xTSPSel, 1);
            localIdx = (1:app.CommonSignal.signalSize)';
            xTSPSh = tmp.xTSPSel(fftl / 2 + localIdx - signalSize / 2, :);
            app.CommonSignal.xTSPBaseSh = xTSPSh;
            nTentativeRepetition = 40; %
            tmpBufferSize = app.CommonSignal.signalSize ...
                + nTentativeRepetition * repetitionSize;
            tmpBuffer = zeros(tmpBufferSize, 2);
            localSign = 1;
            for ii = 1:nTentativeRepetition
                tmpBuffer((ii - 1) * repetitionSize + localIdx, :) = ...
                    tmpBuffer((ii - 1) * repetitionSize + localIdx, :) ...
                    + [xTSPSh(:, 1),  localSign * xTSPSh(:, 2)];
                localSign = -localSign;
            end
            fftl = app.CommonSignal.repetitionSize;
            fs = app.CommonSignal.samplingFreq;
            fLow = 40;
            fx = (0:fftl - 1)' / fftl * fs;
            fx(fx > fs/2) = fx(fx > fs/2) - fs;
            g = 1.0 ./ abs(fx / fLow);
            g(g > 1) = 1;
            rawAutoCorr = real(ifft(g));
            rawAutoCorr = rawAutoCorr / rawAutoCorr(1);
            np = 50;
            [a, ~] = levinson(rawAutoCorr, np);
            app.CommonSignal.fLow = fLow;
            app.CommonSignal.pinkLPC = a;
            y = filter(1, app.CommonSignal.pinkLPC, tmpBuffer);
            app.CommonSignal.loopSignal = ...
                y(fftl * 8 + (1:repetitionSize * 8), :);
            app.CommonSignal.loopSignal = app.CommonSignal.loopSignal ...
                ./ max(abs(app.CommonSignal.loopSignal)) * 0.9;
            results = tmp.fs;
        end
        
        function results = initializeLevelMeter(app)
            if app.CommonSignal.calibrationConst == 0
                powerDispSize = app.PlotAttributes.powerDispSize;
                lvl = -20 + 20*log10(std(randn(powerDispSize, 1)));
                peakLvl = -10;
                app.PlotAttributes.levelHandle = bar(app.LevelAxes, 1, lvl + 100);
                xlim = get(app.LevelAxes, 'xlim');
                hold(app.LevelAxes, "on")
                app.PlotAttributes.peakHandle = ...
                    plot(app.LevelAxes, xlim, peakLvl + 100 + xlim * 0 ,'r', ...
                    'linewidth', 3);
                hold(app.LevelAxes, "off")
                set(app.LevelAxes, 'xtick', [], 'ylim', [0 100], ...
                    'ytick', 0:10:100, 'yticklabel', num2str((-100:10:0)'), ...
                    'box', 'on'); grid(app.LevelAxes, 'on');
                xlabel(app.LevelAxes, ' ');
                ylabel(app.LevelAxes, 'level (dB re. MSB)');
                title(app.LevelAxes, 'input');
                results = lvl;
            else
                results = -20;
            end
        end
        
        function results = initializeResponseViewer(app)
            respDispSize = app.CommonSignal.repetitionSize;
            fs = app.CommonSignal.samplingFreq;
            randSig = randn(respDispSize, 1);
            randSig = randSig / max(abs(randSig));
            randSig2 = randn(respDispSize, 1);
            randSig2 = randSig2 / max(abs(randSig2));
            app.PlotAttributes.respHandle1 = ...
                plot(app.ResponseAxes, (1:respDispSize) / fs, randSig);
            hold(app.ResponseAxes, "on")
            app.PlotAttributes.respHandle2 = ...
                plot(app.ResponseAxes, (1:respDispSize) / fs, randSig2);
            set(app.ResponseAxes, 'xlim', [0 respDispSize/fs], 'ylim', [-1 1], ...
                'box', 'on');grid(app.ResponseAxes, 'on');
            hold(app.ResponseAxes, "off")
            app.CommonSignal.xUp = respDispSize/fs;
            app.CommonSignal.xPan = 0;
            xlabel(app.ResponseAxes, 'time (s)')
            title(app.ResponseAxes, 'impulse response');
            results = respDispSize;
        end
        
        function results = initializeFreqRespViewer(app)
            respDispSize = app.CommonSignal.repetitionSize;
            fs = app.CommonSignal.samplingFreq;
            fx = (0:respDispSize-1)/respDispSize * fs;
            pwResp1 = abs(fft(randn(respDispSize, 1))) .^ 2 / respDispSize;
            pwResp2 = abs(fft(randn(respDispSize, 1))) .^ 2 / respDispSize;
            pwResp3 = abs(fft(randn(respDispSize, 1))) .^ 2 / respDispSize;
            app.PlotAttributes.freqRespHandle1 = ...
                semilogx(app.FreqRespAxes, fx, 10*log10(pwResp1));
            hold(app.FreqRespAxes, "on")
            app.PlotAttributes.freqRespHandle2 = ...
                semilogx(app.FreqRespAxes, fx, 10*log10(pwResp2)-20);
            app.PlotAttributes.freqRespHandle3 = ...
                semilogx(app.FreqRespAxes, fx, 10*log10(pwResp3)-40);
            hold(app.FreqRespAxes, "off")
            set(app.FreqRespAxes, 'xlim', [10 fs /2], "ylim", [-50 30], ...
                "box", "on");
            grid(app.FreqRespAxes, 'on');
            xlabel(app.FreqRespAxes, 'frequency (Hz)')
            ylabel(app.FreqRespAxes, 'level (dB rel.)')
            title(app.FreqRespAxes, 'frequency response');
            app.PlotAttributes.frequencyAxis = fx;
            results = fs;
        end
        
        function results = initializePowerRespViewer(app)
            respDispSize = app.CommonSignal.repetitionSize;
            fs = app.CommonSignal.samplingFreq;
            w = hanning(round(0.0025 * fs)); % 2.5 ms smoother
            app.PlotAttributes.powerSmoother = w;
            results = fs;
            tt = (1:respDispSize)'/fs;
            envShape = exp(-tt/0.02);
            pwResp1 = 10 * log10(fftfilt(w,abs(randn(respDispSize, 1)) ...
                .^ 2 .* envShape));
            pwResp2 = 10 * log10(fftfilt(w,abs(randn(respDispSize, 1)) ...
                .^ 2 .* envShape));
            app.PlotAttributes.powerRespHandle1 = ...
                plot(app.PowerDecayAxes, tt, pwResp1 - max(pwResp1));
            hold(app.PowerDecayAxes, "on")
            app.PlotAttributes.powerRespHandle2 = ...
                plot(app.PowerDecayAxes, tt, pwResp2 - max(pwResp2));
            hold(app.PowerDecayAxes, "off")
            set(app.PowerDecayAxes, 'xlim', [0 tt(end)], "ylim", [-80 0], ...
                "box", "on");
            grid(app.PowerDecayAxes, 'on');
            xlabel(app.PowerDecayAxes, 'time (s)')
            ylabel(app.PowerDecayAxes, 'level (dB)')
            title(app.PowerDecayAxes, 'power response');
        end
        
        
        function results = assignDataDirectory(app)
            outPath = uigetdir('', 'Select output file directory:');
            app.CommonSignal.outPath = outPath;
            nameOfLog = ['capriLogT' datestr(now, 30) '.txt'];
            app.CommonSignal.fileID = fopen([outPath '/' nameOfLog], 'w');
            fprintf(app.CommonSignal.fileID, 'Start: %s\n', ...
                datestr(now));
            fprintf(app.CommonSignal.fileID,'computer: %s\n', computer);
            app.CommonSignal.startTime = now;
            results = 'ok';
        end
        
        function analysisStrCore = specStrDistill(app)
            analysisStr = app.CommonSignal.analysisStr;
            analysisStrCore = struct;
            analysisStrCore.yRecorded = analysisStr.yRecorded;
            analysisStrCore.pinkLPC = analysisStr.pinkLPC;
            analysisStrCore.fs = analysisStr.fs;
            analysisStrCore.tResponse = analysisStr.tResponse;
            analysisStrCore.nRepetition = analysisStr.nRepetition;
            analysisStrCore.outChannel = analysisStr.outChannel;
            analysisStrCore.numChannels = analysisStr.numChannels;
            analysisStrCore.selectedChannels = analysisStr.selectedChannels;
            analysisStrCore.lAeq = analysisStr.lAeq;
            analysisStrCore.calibrationConst = analysisStr.calibrationConst;
        end
        
        function results = initializeDeviceCondition(app)
            fs = 44100;
            nBits = 24;
            inChannel = 2;
            if audiodevinfo(1,-1,fs,nBits,inChannel)
                results = 1;
                app.CommonSignal.twoChannel = 1;
            else
                app.CommonSignal.twoChannel = 0;
                results = 0;
            end
            fsOffline = 192;
            if audiodevinfo(1,-1,fsOffline,nBits,1)
                app.CommonSignal.highSROK = 1;
            else
                app.CommonSignal.highSROK = 0;%???
            end
            close(app.CommonSignal.f);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeTestSignal(app);
            initializeDeviceCondition(app);
            if app.CommonSignal.highSROK
                app.SRofflnDropDown.Enable = 'on';
            else
                app.SRofflnDropDown.Enable = 'off';
            end
            app.PlotAttributes.responseSize = 1024;
            app.PlotAttributes.powerDispSize = 8192;
            app.STOPButton.Enable = 'off';
            app.LevelAxes.Toolbar.Visible = 'off';
            app.FreqRespAxes.Toolbar.Visible = 'off';
            app.PowerDecayAxes.Toolbar.Visible = 'off';
            app.ResponseAxes.Toolbar.Visible = 'off';
            app.RECLamp.Color = 0.8 * [1 1 1];
            disableDefaultInteractivity(app.LevelAxes);
            disableDefaultInteractivity(app.FreqRespAxes);
            disableDefaultInteractivity(app.PowerDecayAxes);
            disableDefaultInteractivity(app.ResponseAxes);
            initializeLevelMeter(app);
            initializeResponseViewer(app);
            initializeFreqRespViewer(app);
            initializePowerRespViewer(app);
            app.SmoothingDropDown.Enable = 'off';
            app.DispBiasDropDown.Enable = 'on';
            app.RespmsDropDown.Enable = 'on';
            if app.CommonSignal.twoChannel
                app.INPUTDropDown.Enable = 'on';
            else
                app.INPUTDropDown.Enable = 'off';
            end
            app.A70dBButton.Enable = 'off';
            app.A80dBButton.Enable = 'off';
            app.RESETButton.Enable = 'off';
            app.A70dBButton_R.Enable = 'off';
            app.A80dBButton_R.Enable = 'off';
            app.RESETButton_R.Enable = 'off';
            app.DISPLAYDropDown.Enable = 'off';
            app.LOUTMEASUREButton.Enable = 'off';
            app.LOUTMEASUREButton.BusyAction = 'cancel';
            app.ROUTMEASUREButton.Enable = 'off';
            app.ROUTMEASUREButton.BusyAction = 'cancel';
            app.VoiceMemoCounterLabel.Visible = 'off';
            if ~(app.CommonSignal.calibrationConst == 0)
                app.LOUTMEASUREButton.Enable = 'on';
                app.LLamp.Color = [0 1 0];
            else
                app.LLamp.Color = [1 1 1] * 0.8;
            end
            if ~(app.CommonSignal.calibrationConst_R == 0)
                app.ROUTMEASUREButton.Enable = 'on';
                app.RLamp.Color = [0 1 0];
            else
                app.RLamp.Color = [1 1 1] * 0.8;
            end
            app.VOICEMEMOButton.Enable = 'on';
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            if ~isempty(app.CommonSignal.outPath) && isfield(app.CommonSignal, 'fileID')
                fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                    + " application_terminated ");
                fclose(app.CommonSignal.fileID);
            end
            close(app.ARTester);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.LOUTMEASUREButton.Enable = 'off';
            app.ROUTMEASUREButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.VOICEMEMOButton.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            if isempty(app.CommonSignal.outPath)
                assignDataDirectory(app);
            end
            value = app.RespmsDropDown.Value;
            channlelValue = app.INPUTDropDown.Value;
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " online_started roop_ms:" + value + " input:" + channlelValue);
            app.CommonSignal.running = 1;
            app.STOPButton.Enable = 'on';
            app.QUITButton.Enable = 'off';
            app.SmoothingDropDown.Enable = 'on';
            app.DispBiasDropDown.Enable = 'on';
            app.RespmsDropDown.Enable = 'off';
            if app.CommonSignal.calibrationConst == 0 || ...
                    app.CommonSignal.calibrationConst_R == 0
            switch app.DISPLAYDropDown.Value
                case 'L-CH'
                    app.A70dBButton.Enable = 'on';
                    app.A80dBButton.Enable = 'on';
                    app.RESETButton.Enable = 'off';
                    app.A70dBButton_R.Enable = 'off';
                    app.A80dBButton_R.Enable = 'off';
                case 'R-CH'
                    if app.CommonSignal.twoChannel
                        app.A70dBButton.Enable = 'off';
                        app.A80dBButton.Enable = 'off';
                        app.A70dBButton_R.Enable = 'on';
                        app.A80dBButton_R.Enable = 'on';
                        app.RESETButton_R.Enable = 'off';
                    end
            end
            end
            app.INPUTDropDown.Enable = 'off';
            app.ACTIONDropDown.Enable = 'off';
            app.DISPLAYDropDown.Enable = 'off';
            app.ResultsINSPECTORButton.Enable = 'off';
            app.RECLamp.Color = [1 0 0];
            %------------- D/A and A/D loop --------------
            updateAudioLoop(app);
            %------------- completed the loop ------------
            app.RECLamp.Color = 0.8 * [1 1 1];
            app.ACTIONDropDown.Enable = 'on';
            app.ResultsINSPECTORButton.Enable = 'on';
            if app.CommonSignal.twoChannel
                app.INPUTDropDown.Enable = 'on';
            else
                app.INPUTDropDown.Enable = 'off';
            end
            if app.CommonSignal.calibrationConst ~= 0
                app.RESETButton.Enable = 'on';
            end
            switch app.INPUTDropDown.Value
                case 'LR-CH'
                    app.DISPLAYDropDown.Enable = 'on';
            end
            switch app.DISPLAYDropDown.Value
                case 'L-CH'
                    app.A70dBButton.Enable = 'off';
                    app.A80dBButton.Enable = 'off';
                    app.RESETButton.Enable = 'on';
                    app.A70dBButton_R.Enable = 'off';
                    app.A80dBButton_R.Enable = 'off';
                case 'R-CH'
                    if app.CommonSignal.twoChannel
                        app.A70dBButton.Enable = 'off';
                        app.A80dBButton.Enable = 'off';
                        app.A70dBButton_R.Enable = 'off';
                        app.A80dBButton_R.Enable = 'off';
                        app.RESETButton_R.Enable = 'on';
                    end
            end
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " online_ended ");
            app.VOICEMEMOButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.QUITButton.Enable = 'on';
            app.SmoothingDropDown.Enable = 'off';
            app.DispBiasDropDown.Enable = 'off';
            app.RespmsDropDown.Enable = 'on';
            app.LOUTMEASUREButton.Enable = 'on';
            app.ROUTMEASUREButton.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.CommonSignal.running = 0;
            app.STOPButton.Enable = 'off';
        end

        % Value changing function: magSlider
        function magSliderValueChanging(app, event)
            changingValue = event.Value;
            fs = app.CommonSignal.samplingFreq;
            %app.PlayOrCounter.magnify = changingValue;
            app.CommonSignal.xUp = app.CommonSignal.repetitionSize / fs / changingValue;
            set(app.ResponseAxes, 'xlim', app.CommonSignal.xPan + [0 app.CommonSignal.xUp]);
        end

        % Value changed function: RespmsDropDown
        function RespmsDropDownValueChanged(app, event)
            value = app.RespmsDropDown.Value;
            app.CommonSignal.periodText = value;
            startupFcn(app);
        end

        % Value changing function: panSlider
        function panSliderValueChanging(app, event)
            changingValue = event.Value;
            %fs = app.CommonSignal.samplingFreq;
            app.CommonSignal.xPan = -changingValue / 1000;
            set(app.ResponseAxes, 'xlim', app.CommonSignal.xPan + [0 app.CommonSignal.xUp]);
        end

        % Button pushed function: A80dBButton
        function A80dBButtonPushed(app, event)
            tmpSig = app.CommonSignal.weightFilt(app.CommonSignal.readBuffer);
            tmpSigDb = 20*log10(std(tmpSig(app.CommonSignal.updateSize:end)));
            app.CommonSignal.calibrationConst = 80 - tmpSigDb;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
            app.A70dBButton.Enable = 'off';
            app.A80dBButton.Enable = 'off';
            app.LLamp.Color = [0 1 0];
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibrated_at80dB calibration_coefficient:" + num2str(app.CommonSignal.calibrationConst) ...
                + " dB");
        end

        % Button pushed function: A70dBButton
        function A70dBButtonPushed(app, event)
            tmpSig = app.CommonSignal.weightFilt(app.CommonSignal.readBuffer);
            tmpSigDb = 20*log10(std(tmpSig(app.CommonSignal.updateSize:end)));
            app.CommonSignal.calibrationConst = 70 - tmpSigDb;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
            app.A70dBButton.Enable = 'off';
            app.A80dBButton.Enable = 'off';
            app.LLamp.Color = [0 1 0];
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibrated_at70dB calibration_coefficient:" + num2str(app.CommonSignal.calibrationConst) ...
                + " dB");
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.CommonSignal.calibrationConst = 0;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'level (dB re. MSB)');
            app.RESETButton.Enable = 'off';
            app.LLamp.Color = [1 1 1] * 0.8;
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibration_reset ");
        end

        % Button pushed function: VOICEMEMOButton
        function VOICEMEMOButtonPushed(app, event)
            app.LOUTMEASUREButton.Enable = 'off';
            app.ROUTMEASUREButton.Enable = 'off';
            app.ACTIONDropDown.Enable = 'off';
            app.ResultsINSPECTORButton.Enable = 'off';
            if isempty(app.CommonSignal.outPath)
                assignDataDirectory(app);
            end
            app.VOICEMEMOButton.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            app.memoData.fs = 44100;
            fs = app.memoData.fs;
            %app.memoData.audioRecorder = audiorecorder(app.memoData.fs, 24, 1);
            app.VoiceMemoCounterLabel.Visible = 'on';
            tDuration = 5;
            app.memoData.audioRecorder = audioDeviceReader(app.memoData.fs,...
                1024,"BitDepth","24-bit integer","Device", app.CommonSignal.DeviceName);
            y = zeros(app.memoData.fs * tDuration, 1);
            nFrames = floor(tDuration * fs / 1024);
            for ii = 1:nFrames
                [inputData, ~] = app.memoData.audioRecorder();
                y((ii - 1) * 1024 + (1:1024)) = inputData;
                app.VoiceMemoCounterLabel.Text = num2str(tDuration - ii * 1024 / fs, '%04.2f');
                drawnow limitrate
            end
            release(app.memoData.audioRecorder);
            app.VoiceMemoCounterLabel.Text = '5';
            drawnow
            %app.VoiceMemoCounterLabel.Visible = 'on';
            %record(app.memoData.audioRecorder);
            %for ii = tDuration:-0.1:0
            %    app.VoiceMemoCounterLabel.Text = num2str(ii);
            %    pause(0.1);
            %end
            %y = getaudiodata(app.memoData.audioRecorder);
            %stop(app.memoData.audioRecorder);
            app.VoiceMemoCounterLabel.Visible = 'off';
            memoName = "memo" + datestr(now, 30) + ".wav";
            audiowrite(app.CommonSignal.outPath + "/" + memoName, y, app.memoData.fs, "BitsPerSample", 24);
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " memo_recorded " + memoName);
            app.ACTIONDropDown.Enable = 'on';
            app.ResultsINSPECTORButton.Enable = 'on';
            app.VOICEMEMOButton.Enable = 'on';
            app.LOUTMEASUREButton.Enable = 'on';
            app.ROUTMEASUREButton.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
        end

        % Button pushed function: LOUTMEASUREButton
        function LOUTMEASUREButtonPushed(app, event)
            set(app.LOUTMEASUREButton, 'Enable', 'off');
            app.ROUTMEASUREButton.Enable = 'off';
            app.ACTIONDropDown.Enable = 'off';
            app.ResultsINSPECTORButton.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            app.INPUTDropDown.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            tResponse = str2double(app.RespmsDropDown.Value);
            nRepetition = 30;
            outChannel = 'L-ch';
            option.DeviceName = app.CommonSignal.DeviceName;
            switch app.INPUTDropDown.Value
                case 'L-CH'
                    inChannel = 1;
                    option.calibrationConst = app.CommonSignal.calibrationConst;
                case 'R-CH'
                    inChannel = 2;
                    option.calibrationConst = ...
                        [app.CommonSignal.calibrationConst app.CommonSignal.calibrationConst_R];
                    option.selectedChannels = 2;
                case 'LR-CH'
                    inChannel = 2;
                    option.calibrationConst = ...
                        [app.CommonSignal.calibrationConst app.CommonSignal.calibrationConst_R];
                    option.selectedChannels = [1 2];
            end
            %fs = app.CommonSignal.samplingFreq;
            switch app.SRofflnDropDown.Value
                case '44kHz'
                    fs = 44100;
                case '192kHz'
                    fs = 192000;
            end
            set(app.RECLamp, 'Color', [1 0 0]);
            drawnow
            tmptic = tic;
            analysisStr = capricepResponseTest(fs, tResponse, nRepetition, ...
                outChannel, inChannel, 'acoustic_system', option);
            toc(tmptic)
            app.RECLamp.Color = 0.8 * [1 1 1];
            %specStr = capricepResponseAnalysis(analysisStr);
            fname = "capriData" + datestr(now,30) + ".mat";
            %specStr.dataFile = fname;
            %reportStr = capricepResponseReport(specStr);
            app.CommonSignal.analysisStr = analysisStr;
            analysisStrCore = specStrDistill(app);
            save(app.CommonSignal.outPath + "/" + fname, "analysisStrCore");
            tmp = capResultReporter([char(app.CommonSignal.outPath)   '/'   ...
                char(fname)], 'NONE');
            app.CommonSignal.reportStr = tmp.reportStr;
            set(tmp.reportStr.figureHandleReport,'name', char(fname));
            app.ACTIONDropDown.Enable = 'on';
            app.ResultsINSPECTORButton.Enable = 'on';
            app.LOUTMEASUREButton.Enable = 'on';
            app.ROUTMEASUREButton.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
            app.INPUTDropDown.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
        end

        % Button pushed function: ROUTMEASUREButton
        function ROUTMEASUREButtonPushed(app, event)
            app.LOUTMEASUREButton.Enable = 'off';
            app.ROUTMEASUREButton.Enable = 'off';
            app.ACTIONDropDown.Enable = 'off';
            app.ResultsINSPECTORButton.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            app.INPUTDropDown.Enable = 'off';
            app.SRofflnDropDown.Enable = 'off';
            tResponse = str2double(app.RespmsDropDown.Value);
            nRepetition = 30;
            outChannel = 'R-ch';
            option.DeviceName = app.CommonSignal.DeviceName;
            switch app.INPUTDropDown.Value
                case 'L-CH'
                    inChannel = 1;
                    option.calibrationConst = app.CommonSignal.calibrationConst;
                case 'R-CH'
                    inChannel = 2;
                    option.calibrationConst = ...
                        [app.CommonSignal.calibrationConst app.CommonSignal.calibrationConst_R];
                    option.selectedChannels = 2;
                case 'LR-CH'
                    inChannel = 2;
                    option.calibrationConst = ...
                        [app.CommonSignal.calibrationConst app.CommonSignal.calibrationConst_R];
                    option.selectedChannels = [1 2];
            end
            %fs = app.CommonSignal.samplingFreq;
            switch app.SRofflnDropDown.Value
                case '44kHz'
                    fs = 44100;
                case '192kHz'
                    fs = 192000;
            end
            app.RECLamp.Color = [1 0 0];
            drawnow;
            analysisStr = capricepResponseTest(fs, tResponse, nRepetition, ...
                outChannel, inChannel, 'acoustic_system', option);
            app.RECLamp.Color = 0.8 * [1 1 1];
            %specStr = capricepResponseAnalysis(analysisStr);
            fname = "capriData" + datestr(now,30) + ".mat";
            %specStr.dataFile = fname;
            %reportStr = capricepResponseReport(specStr);
            app.CommonSignal.analysisStr = analysisStr;
            analysisStrCore = specStrDistill(app);
            save(app.CommonSignal.outPath + "/" + fname, "analysisStrCore");
            tmp = capResultReporter([char(app.CommonSignal.outPath)   '/'   ...
                char(fname)], 'NONE');
            app.CommonSignal.reportStr = tmp.reportStr;
            set(tmp.reportStr.figureHandleReport,'name', char(fname));
            app.ACTIONDropDown.Enable = 'on';
            app.ResultsINSPECTORButton.Enable = 'on';
            app.LOUTMEASUREButton.Enable = 'on';
            app.ROUTMEASUREButton.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
            app.INPUTDropDown.Enable = 'on';
            app.SRofflnDropDown.Enable = 'on';
        end

        % Button pushed function: ResultsINSPECTORButton
        function ResultsINSPECTORButtonPushed(app, event)
            %displayMode = app.ACTIONDropDown.Value;
            [file,path] = uigetfile('*.mat', 'Select CAPRICEP data');
            %disp(file)
            %disp(path)
            [~,fname,~] = fileparts([path file]);
            tmp = load([path file]);
            if isfield(tmp, 'analysisStrCore')
                tmp = capResultReporter([path file], 'NONE');
                app.CommonSignal.reportStr = tmp.reportStr;
                set(tmp.reportStr.figureHandleReport,'name', char(fname));
            end
        end

        % Value changed function: ACTIONDropDown
        function ACTIONDropDownValueChanged(app, event)
            value = app.ACTIONDropDown.Value;
            if ishandle(app.CommonSignal.reportStr.figureHandleReport)
                nameRoot = get(app.CommonSignal.reportStr.figureHandleReport,'name');
                switch value
                    case 'EPSF'
                        defname = [nameRoot '.eps'];
                    case 'PNG'
                        defname = [nameRoot '.png'];
                    case 'PDF'
                        defname = [nameRoot '.pdf'];
                    case 'JPEG'
                        defname = [nameRoot '.jpeg'];
                end
                [file, path] = uiputfile('*.*', 'Outfile selection', defname);
                switch value
                    case 'EPSF'
                        print(app.CommonSignal.reportStr.figureHandleReport,'-depsc', [path, file]);
                    case 'PNG'
                        print(app.CommonSignal.reportStr.figureHandleReport,'-dpng', '-r200', [path, file]);
                    case 'JPEG'
                        print(app.CommonSignal.reportStr.figureHandleReport,'-djpeg', '-r200', [path, file]);
                    case 'PDF'
                        print(app.CommonSignal.reportStr.figureHandleReport,'-dpdf', '-bestfit', [path, file]);
                end
            end
            set(app.ACTIONDropDown, 'Value', 'NONE');
        end

        % Button pushed function: A70dBButton_R
        function A70dBButton_RPushed(app, event)
            tmpSig = app.CommonSignal.weightFilt(app.CommonSignal.readBuffer);
            tmpSigDb = 20*log10(std(tmpSig(app.CommonSignal.updateSize:end)));
            app.CommonSignal.calibrationConst_R = 70 - tmpSigDb;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst_R, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
            app.A70dBButton_R.Enable = 'off';
            app.A80dBButton_R.Enable = 'off';
            app.RLamp.Color = [0 1 0];
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibrated_R_at70dB calibration_coefficient:" + num2str(app.CommonSignal.calibrationConst) ...
                + " dB");
        end

        % Button pushed function: A80dBButton_R
        function A80dBButton_RPushed(app, event)
            tmpSig = app.CommonSignal.weightFilt(app.CommonSignal.readBuffer);
            tmpSigDb = 20*log10(std(tmpSig(app.CommonSignal.updateSize:end)));
            app.CommonSignal.calibrationConst_R = 80 - tmpSigDb;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst_R, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
            app.A70dBButton_R.Enable = 'off';
            app.A80dBButton_R.Enable = 'off';
            app.RLamp.Color = [0 1 0];
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibrated_R_at80dB calibration_coefficient:" + num2str(app.CommonSignal.calibrationConst) ...
                + " dB");
        end

        % Button pushed function: RESETButton_R
        function RESETButton_RPushed(app, event)
            app.CommonSignal.calibrationConst_R = 0;
            set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                num2str((-100:10:0)' + app.CommonSignal.calibrationConst_R, ...
                '%5.1f'));
            ylabel(app.LevelAxes, 'level (dB re. MSB)');
            app.RESETButton_R.Enable = 'off';
            app.RLamp.Color = [1 1 1] * 0.8;
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " calibration_R_reset ");
        end

        % Value changed function: INPUTDropDown
        function INPUTDropDownValueChanged(app, event)
            value = app.INPUTDropDown.Value;
            switch value
                case 'L-CH'
                    set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                        num2str((-100:10:0)' + app.CommonSignal.calibrationConst, ...
                        '%5.1f'));
                    app.DISPLAYDropDown.Value = 'L-CH';
                    app.DISPLAYDropDown.Enable = 'off';
                    if app.CommonSignal.calibrationConst == 0
                        ylabel(app.LevelAxes, 'level (dB re. MSB)');
                    else
                        ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
                    end
                case 'R-CH'
                    set(app.LevelAxes, 'ytick', 0:10:100, 'yticklabel', ...
                        num2str((-100:10:0)' + app.CommonSignal.calibrationConst_R, ...
                        '%5.1f'));
                    app.DISPLAYDropDown.Value = 'R-CH';
                    app.DISPLAYDropDown.Enable = 'off';
                    if app.CommonSignal.calibrationConst_R == 0
                        ylabel(app.LevelAxes, 'level (dB re. MSB)');
                    else
                        ylabel(app.LevelAxes, 'sound pressure level (dB re. 20\mu Pa)');
                    end
                case 'LR-CH'
                    if app.CommonSignal.twoChannel
                        app.DISPLAYDropDown.Enable = 'on';
                    end
            end
            fprintf(app.CommonSignal.fileID, '%s\n',  num2str((now - app.CommonSignal.startTime) * 86400, '%15.3f') ...
                + " INPUT_Channel_changed_to " + string(value));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ARTester.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {723, 723};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARTester = uifigure('Visible', 'off');
            app.ARTester.Name = 'MATLAB App';
            app.ARTester.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ARTester);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.AcousticresponsetesterLabel = uilabel(app.LeftPanel);
            app.AcousticresponsetesterLabel.Text = 'Acoustic response tester';
            app.poweredbyCAPRICEPLabel = uilabel(app.LeftPanel);
            app.poweredbyCAPRICEPLabel.Text = 'powered by CAPRICEP';
            app.VOICEMEMOButton = uibutton(app.LeftPanel, 'push');
            app.VOICEMEMOButton.ButtonPushedFcn = createCallbackFcn(app, @VOICEMEMOButtonPushed, true);
            app.VOICEMEMOButton.Text = 'VOICE-MEMO';
            app.VoiceMemoCounterLabel = uilabel(app.LeftPanel);
            app.VoiceMemoCounterLabel.Text = '5';
            app.OfflinetoolsPanel = uipanel(app.LeftPanel);
            app.OfflinetoolsPanel.Title = 'Off-line tools';
            app.LOUTMEASUREButton = uibutton(app.OfflinetoolsPanel, 'push');
            app.LOUTMEASUREButton.ButtonPushedFcn = createCallbackFcn(app, @LOUTMEASUREButtonPushed, true);
            app.LOUTMEASUREButton.Text = 'L-OUT-MEASURE';
            app.ROUTMEASUREButton = uibutton(app.OfflinetoolsPanel, 'push');
            app.ROUTMEASUREButton.ButtonPushedFcn = createCallbackFcn(app, @ROUTMEASUREButtonPushed, true);
            app.ROUTMEASUREButton.Text = 'R-OUT-MEASURE';
            app.INPUTDropDownLabel = uilabel(app.OfflinetoolsPanel);
            app.INPUTDropDownLabel.Text = 'INPUT';
            app.INPUTDropDown = uidropdown(app.OfflinetoolsPanel);
            app.INPUTDropDown.Items = {'L-CH', 'R-CH', 'LR-CH'};
            app.INPUTDropDown.ValueChangedFcn = createCallbackFcn(app, @INPUTDropDownValueChanged, true);
            app.INPUTDropDown.Value = 'L-CH';
            app.ResultsINSPECTORButton = uibutton(app.OfflinetoolsPanel, 'push');
            app.ResultsINSPECTORButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsINSPECTORButtonPushed, true);
            app.ResultsINSPECTORButton.Text = 'Results INSPECTOR';
            app.ACTIONDropDownLabel = uilabel(app.OfflinetoolsPanel);
            app.ACTIONDropDownLabel.Text = 'ACTION';
            app.ACTIONDropDown = uidropdown(app.OfflinetoolsPanel);
            app.ACTIONDropDown.Items = {'NONE', 'JPEG', 'PDF', 'PNG', 'EPSF'};
            app.ACTIONDropDown.ValueChangedFcn = createCallbackFcn(app, @ACTIONDropDownValueChanged, true);
            app.ACTIONDropDown.Value = 'NONE';
            app.SRofflnDropDownLabel = uilabel(app.OfflinetoolsPanel);
            app.SRofflnDropDownLabel.Text = 'SR-offln';
            app.SRofflnDropDown = uidropdown(app.OfflinetoolsPanel);
            app.SRofflnDropDown.Items = {'44kHz', '192kHz'};
            app.SRofflnDropDown.Value = '44kHz';
            app.DeviceLabel = uilabel(app.OfflinetoolsPanel);
            app.DeviceLabel.Text = 'Device';
            app.DriverLabel = uilabel(app.OfflinetoolsPanel);
            app.DriverLabel.Text = 'Driver';
            app.CalibrationPanel = uipanel(app.LeftPanel);
            app.CalibrationPanel.Title = 'Calibration';
            app.A70dBButton = uibutton(app.CalibrationPanel, 'push');
            app.A70dBButton.ButtonPushedFcn = createCallbackFcn(app, @A70dBButtonPushed, true);
            app.A70dBButton.Text = 'A-70dB';
            app.A80dBButton = uibutton(app.CalibrationPanel, 'push');
            app.A80dBButton.ButtonPushedFcn = createCallbackFcn(app, @A80dBButtonPushed, true);
            app.A80dBButton.Text = 'A-80dB';
            app.RESETButton = uibutton(app.CalibrationPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.A80dBButton_R = uibutton(app.CalibrationPanel, 'push');
            app.A80dBButton_R.ButtonPushedFcn = createCallbackFcn(app, @A80dBButton_RPushed, true);
            app.A80dBButton_R.Text = 'A-80dB';
            app.A70dBButton_R = uibutton(app.CalibrationPanel, 'push');
            app.A70dBButton_R.ButtonPushedFcn = createCallbackFcn(app, @A70dBButton_RPushed, true);
            app.A70dBButton_R.Text = 'A-70dB';
            app.RESETButton_R = uibutton(app.CalibrationPanel, 'push');
            app.RESETButton_R.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_RPushed, true);
            app.RESETButton_R.Text = 'RESET';
            app.LLampLabel = uilabel(app.CalibrationPanel);
            app.LLampLabel.Text = 'L';
            app.LLamp = uilamp(app.CalibrationPanel);
            app.RLampLabel = uilabel(app.CalibrationPanel);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.CalibrationPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.ResponseAxes = uiaxes(app.RightPanel);
            title(app.ResponseAxes, 'Title')
            xlabel(app.ResponseAxes, 'X')
            ylabel(app.ResponseAxes, 'Y')
            zlabel(app.ResponseAxes, 'Z')
            app.PowerDecayAxes = uiaxes(app.RightPanel);
            title(app.PowerDecayAxes, 'Title')
            xlabel(app.PowerDecayAxes, 'X')
            ylabel(app.PowerDecayAxes, 'Y')
            zlabel(app.PowerDecayAxes, 'Z')
            app.FreqRespAxes = uiaxes(app.RightPanel);
            title(app.FreqRespAxes, 'Title')
            xlabel(app.FreqRespAxes, 'X')
            ylabel(app.FreqRespAxes, 'Y')
            zlabel(app.FreqRespAxes, 'Z')
            app.LevelAxes = uiaxes(app.RightPanel);
            title(app.LevelAxes, 'Title')
            xlabel(app.LevelAxes, 'X')
            ylabel(app.LevelAxes, 'Y')
            zlabel(app.LevelAxes, 'Z')
            app.PlayOrCounter = uilabel(app.RightPanel);
            app.PlayOrCounter.Text = '0';
            app.RecOrCounter = uilabel(app.RightPanel);
            app.RecOrCounter.Text = '0';
            app.DispBiasDropDownLabel = uilabel(app.RightPanel);
            app.DispBiasDropDownLabel.Text = 'DispBias';
            app.DispBiasDropDown = uidropdown(app.RightPanel);
            app.DispBiasDropDown.Items = {'0', '1', '2', '3'};
            app.DispBiasDropDown.Value = '3';
            app.magSliderLabel = uilabel(app.RightPanel);
            app.magSliderLabel.Text = 'mag';
            app.magSlider = uislider(app.RightPanel);
            app.magSlider.Limits = [1 10];
            app.magSlider.ValueChangingFcn = createCallbackFcn(app, @magSliderValueChanging, true);
            app.magSlider.Value = 1;
            app.SmoothingDropDownLabel = uilabel(app.RightPanel);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.RightPanel);
            app.SmoothingDropDown.Items = {'ON', 'OFF'};
            app.SmoothingDropDown.Value = 'ON';
            app.RespmsDropDownLabel = uilabel(app.RightPanel);
            app.RespmsDropDownLabel.Text = 'Resp-ms';
            app.RespmsDropDown = uidropdown(app.RightPanel);
            app.RespmsDropDown.Items = {'100', '200', '400', '800'};
            app.RespmsDropDown.ValueChangedFcn = createCallbackFcn(app, @RespmsDropDownValueChanged, true);
            app.RespmsDropDown.Value = '200';
            app.panSliderLabel = uilabel(app.RightPanel);
            app.panSliderLabel.Text = 'pan';
            app.panSlider = uislider(app.RightPanel);
            app.panSlider.Limits = [-10 0];
            app.panSlider.ValueChangingFcn = createCallbackFcn(app, @panSliderValueChanging, true);
            app.DISPLAYDropDownLabel = uilabel(app.RightPanel);
            app.DISPLAYDropDownLabel.Text = 'DISPLAY';
            app.DISPLAYDropDown = uidropdown(app.RightPanel);
            app.DISPLAYDropDown.Items = {'L-CH', 'R-CH'};
            app.DISPLAYDropDown.Value = 'L-CH';
            app.RECLampLabel = uilabel(app.RightPanel);
            app.RECLampLabel.Text = 'REC';
            app.RECLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.ARTester.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeTester

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARTester)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARTester)
        end
    end
end